classdef OptodeReg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadHeadModelMenu             matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        SaveHeadModelButton           matlab.ui.control.Button
        RegModeTabGroup               matlab.ui.container.TabGroup
        SurfaceTab                    matlab.ui.container.Tab
        ShowSurfRegistrationResultsButton  matlab.ui.control.Button
        HeadTemplateDropDown          matlab.ui.control.DropDown
        SelectHeadTemplateDropDownLabel  matlab.ui.control.Label
        RegisterSurfaceButton         matlab.ui.control.Button
        SurfaceIdentificationButtonGroup  matlab.ui.container.ButtonGroup
        SurfaceContigRegionsCheckBox  matlab.ui.control.CheckBox
        SurfaceColorThresholdSlider   matlab.ui.control.Slider
        SurfaceColorThresholdSliderLabel  matlab.ui.control.Label
        SurfaceSubtractfromSelectionButton  matlab.ui.control.ToggleButton
        SurfaceAddtoSelectionButton   matlab.ui.control.ToggleButton
        SurfaceNewSelectionButton     matlab.ui.control.ToggleButton
        OptodesTab                    matlab.ui.container.Tab
        AlignOptodesButton            matlab.ui.control.Button
        OptodeTemplateDropDown        matlab.ui.control.DropDown
        SelectOptodeTemplateDropDownLabel  matlab.ui.control.Label
        RegisterOptodesButton         matlab.ui.control.Button
        OptodeIdentificationButtonGroup  matlab.ui.container.ButtonGroup
        OptodeContigRegionsCheckBox   matlab.ui.control.CheckBox
        OptodeColorThresholdSlider    matlab.ui.control.Slider
        OptodeColorThresholdSliderLabel  matlab.ui.control.Label
        OptodeSubtractfromSelectionButton  matlab.ui.control.ToggleButton
        OptodeAddtoSelectionButton    matlab.ui.control.ToggleButton
        OptodeNewSelectionButton      matlab.ui.control.ToggleButton
        ReorientHeadModelButton       matlab.ui.control.Button
        LoadHeadModelButton           matlab.ui.control.Button
        AxesPanel                     matlab.ui.container.Panel
        AxLabel_orig                  matlab.ui.control.Label
        AxLabel_OptodeColor           matlab.ui.control.Label
        AxLabel_OptodeOrient          matlab.ui.control.Label
        AxLabel_Surface               matlab.ui.control.Label
        UIAxes_orig                   matlab.ui.control.UIAxes
        UIAxes_OptodeColor            matlab.ui.control.UIAxes
        UIAxes_OptodeOrient           matlab.ui.control.UIAxes
        UIAxes_Surface                matlab.ui.control.UIAxes
    end

    %% TODO
    % - support reading zipped head models (zipPy?!)
    % - optode identification: button to remove small spots (ignored
    % anyways)
    % - use a rotate3d object to control whether to rotate or to select
    %   color (use modifier keys...)
    % - ICP to match surface points to standard head (ICBM)
    %    https://de.mathworks.com/help/vision/ref/pcregistericp.html
    %    https://de.mathworks.com/matlabcentral/fileexchange/27804-iterative-closest-point
    %    https://de.mathworks.com/matlabcentral/fileexchange/12627-iterative-closest-point-method
    % - apply transform to headmodel
    % - project detected optodes to head surface
    % - matchpairs(dist,20) to match projected optode positions to position model
    % if that doent work: manually identify some optodes and use
    % estimateGeometricTransform3D to match pairs of optodes, apply the
    % resulting transform and use ICP to improve match?
    % - ui to review/revise Optode assignment
    % - help button for popup with manual


    properties (Access = public)

        config (1,1) struct = struct('UIproperties',struct(), 'lastHeadModelLoadPath','',...
            'lastHeadModelSavePath',''); % struct with app configuration

        lastSlectedOptodeVert (1,1) double = NaN; % last selected vertex for optode color selection
        lastSlectedSurfVert (1,1) double = NaN; % last selected vertex for head surface color selection
        isolatedSurf_MinDiameter (1,1) double = 1; % threshold to remove small islands of isolated faces&vertices for surface registration
        isolatedOpt_MinDiameter (1,1) double = 3; % threshold to remove small islands of isolated faces&vertices for optode registration
        
        hsurfRegisterMethod cell = {'berg'}; % method and paramters for the ICP registration of the head surface
        optodeRegisterMethod cell = {'wilm',100,'Minimize','plane'}; % method and paramters for the ICP registration of the optodes
        optodeMatchingCost (1,1) double = 15; % cost parameter for matchpair() used to assign optode identities; higher values allow higher deviations
        hmSaveFile (1,:) char = ''; % file to save head model to
        
        % For optode registration, should the slected vertices of the
        % optode tops or only their incenters (faster) be used?
        useSelVerts4optRegistration (1,1) logical = false;
    end


    properties (Access = public, AbortSet, SetObservable) % 
        % struct containing the head model and related information
        headModel (1,1) struct = struct('faces', [], 'vertices',[], 'color',[], 'isreoriented',false,...
            'hsruf_isregistered',false, 'optodes_isregistered',false, ...
            'sourceFile','', 'fiducials',struct('Nasion',NaN(1,3),'RPA',NaN(1,3),'LPA',NaN(1,3)), ...
            'tMat',diag(ones(4,1)));
        % struct containing the results of the surface registration and related information
        hsurfRegisterResult (1,1) struct = struct('headTmpl', struct('faces', [], 'vertices',[], 'color',[]),...
                                                  'hsurf',    struct('faces', [], 'vertices',[], 'color',[]));
        % struct containing the results of the optode registration 
        optRegisterResults (1,1) struct = struct(...
             'tmpl_optodePositions', [],    'modl_optodePositions', [], ...
             'tmpl_optodeIDs', [],          'modl_optodeIDs', [], ... 
             'tmpl_headSurface', [],        'modl_headSurface', [], ...
             'tmpl_optodePositions_orig',[],'modl_optodeIsRemoved', [], ...
             'modl_optodeIsManAssigned', [],'modl_optodeIsExtrapolated', []);
        optodeSelectedVerts  (:,1) logical; % selected vertices for optodes
        hsurfSelectedVerts   (:,1) logical; % selected vertices for head surface
    end

    
    properties (Access = public, Constant)
        CFG_FILE (1,1) string = fullfile(fileparts(mfilename("fullpath")),'config.mat'); % path to app config file
        ROOT_PATH (1,1) string = fileparts(fileparts(mfilename("fullpath"))); % app root path
        % fiducials for reorientation to template space, this should be
        % taken from the head model file <- TODO
        FIDUCIALS_MNI (1,1) struct = struct('Nasion',[0.4,85.9,-47.6],'RPA',[83.9,-16.6,-56.7],'LPA',[-83.8,-18.6,-57.2]);
        FIGNAME (1,:) char = 'Optode Registration'; % the name of the UIFigure, displays in the title bar
    end


    properties (SetAccess = private)
%         FT_PATH (1,1) string = ""; % path to fieldtrip toolbox

%         reorientTM (4,4) double = diag(ones(4,1)); % transformation matrix for reorientation
%         surfRegTM (4,4) double = diag(ones(4,1)); % transformation matrix for surface registration
%         headTmpl (1,1) struct; % structure containing the reference head template
        hmModified (1,1) logical = false;
    end


    properties (Access = public, Dependent)
        UImainAxes (:,1) % the 4 head model axes objects
        UIcolorSelectAxes (:,1) % axes objects that contain patches that respond to clicks for color selection
        optodeSelectedSurf (1,1) struct % structure containing the selected optode surface
        hsurfSelectedSurf (1,1) struct % structure containing the selected head surface
        optodeSelectedSurfT (1,1) struct % structure containing the selected transformed optode surface
        hsurfSelectedSurfT (1,1) struct % structure containing the selected transformed head surface
        hasFiducials (1,1) logical % have fiducial been set?
        % the file name of the head template (MNI space)
        headTmplFile (1,1) string %= fullfile("tmpl","mni_icbm152_nlin_sym_09b.mat");
        % the file name of the optode position template (MNI space)
        optodeTmplFile (1,1) string %= fullfile("tmpl","mni_icbm152_nlin_sym_09b.mat");
        headModelT (1,1) struct % the head model with trandormation matrix applied
    end


    properties (Access = private, Hidden, Dependent)
        lastSlectedOptodeVert_ (1,1) double % = NaN; for internal use
        lastSlectedSurfVert_ (1,1) double % = NaN; for internal use
        hsurfRegisterResult_ (1,1) struct
    end


    properties (Hidden)
        % app properties that are saved along with the head model
        % (do not list headModel here!)
        saveProperties (1,:) cell {OptodeReg.mustBeProperty(saveProperties,'OptodeReg')} = {'optodeSelectedVerts','hsurfSelectedVerts','hsurfRegisterResult'};
        headTmplDir (1,1) string = fullfile("tmpl","headSurface"); % rel path to head surface templates
        optodeTmplDir (1,1) string = fullfile("tmpl","optodePositions"); % rel path to optode position templates
    end


    %% ================ get & set methods (Access = public) ===============

    methods
        
        %-------------------------- GET -----------------------------------

        % ####################### get UImainAxes #######################
        
        function hasFids = get.hasFiducials(app)
            if ~isfield(app.headModel,'fiducials'), hasFids = false; return; end
            fs = app.headModel.fiducials;
            fields = {'Nasion','RPA','LPA'};
            hasFids = isstruct(fs) && all(isfield(fs,fields)) && ...
                        all( cellfun(@(x)all(isfinite(fs.(x))), fields) );
        end

        % ####################### get UImainAxes #######################
        
        function ah = get.UImainAxes(app)
            ah = findall(app.AxesPanel.Children,'Type','axes');
        end


        % ####################### get headModelAxes #######################
        
        function ah = get.UIcolorSelectAxes(app)
            ah = [app.UIAxes_orig; app.UIAxes_OptodeColor; app.UIAxes_Surface];
        end


        % #################### get optodeSelectedSurf #####################

        function surf = get.optodeSelectedSurf(app)
            surf = subsetSurf(app.headModel, app.optodeSelectedVerts);
        end
        function surf = get.optodeSelectedSurfT(app)
            surf = subsetSurf(app.headModelT, app.optodeSelectedVerts);
        end


        % ##################### get headSelectedSurf ######################

        function surf = get.hsurfSelectedSurf(app)
            surf = subsetSurf(app.headModel, app.hsurfSelectedVerts);
        end
        function surf = get.hsurfSelectedSurfT(app)
            surf = subsetSurf(app.headModelT, app.hsurfSelectedVerts);
        end


        % ###################### get headModelT ###########################

        function hm = get.headModelT(app)
            hm = app.headModel;
            hm.vertices = ApplyTM(hm.tMat,hm.vertices);
            ff = fieldnames(hm.fiducials);
            for k = 1:numel(ff)
                hm.fiducials.(ff{k}) = ApplyTM(hm.tMat,hm.fiducials.(ff{k}));
            end
            hm.tMat = diag(ones(4,1));
        end


        % ################## get lastSlectedOptodeVert_ ###################
        
        function vertIdx = get.lastSlectedOptodeVert_(app)
            vertIdx = app.lastSlectedOptodeVert;
        end


        % ################### get lastSlectedSurfVert_ ####################
        
        function vertIdx = get.lastSlectedSurfVert_(app)
            vertIdx = app.lastSlectedSurfVert;
        end


        % ################### get lastSlectedSurfVert_ ####################
        
        function surf = get.hsurfRegisterResult_(app)
            surf = app.hsurfRegisterResult;
        end


        % ####################### get headTmplFile ########################

        function headTmplFile = get.headTmplFile(app)
            pth = fullfile(app.ROOT_PATH, app.headTmplDir);
            file = app.HeadTemplateDropDown.Value;
            headTmplFile = fullfile(pth,file);
        end

        % ##################### get optPosTmplFile ########################

        function optodeTmplFile = get.optodeTmplFile(app)
            pth = fullfile(app.ROOT_PATH, app.optodeTmplDir);
            file = app.OptodeTemplateDropDown.Value;
            optodeTmplFile = fullfile(pth,file);
        end

        %-------------------------- SET -----------------------------------

%         % ###################### set config ##############################
%         function set.config(app,config)
%             warning()
%         end

        % ################## set lastSlectedOptodeVert_ ###################
        
        function set.lastSlectedOptodeVert_(app,vertIdx)
            app.lastSlectedOptodeVert = vertIdx;
            liveStr = ' (live)';
            if ~isnan(vertIdx),  sufx = liveStr;
            else,                sufx = '';
            end
            liveStr = regexptranslate('escape',liveStr);

            app.OptodeColorThresholdSliderLabel.Text = ...
                regexprep(app.OptodeColorThresholdSliderLabel.Text, ...
                sprintf('(.)(%s)*$',liveStr),sprintf('$1%s',sufx),'once');
        end


        % ################### set lastSlectedSurfVert_ ####################
        
        function set.lastSlectedSurfVert_(app,vertIdx)
            app.lastSlectedSurfVert = vertIdx;
            liveStr = ' (live)';
            if ~isnan(vertIdx),  sufx = liveStr;
            else,                sufx = '';
            end
            liveStr = regexptranslate('escape',liveStr);

            app.SurfaceColorThresholdSliderLabel.Text = ...
                regexprep(app.SurfaceColorThresholdSliderLabel.Text, ...
                sprintf('(.)(%s)*$',liveStr),sprintf('$1%s',sufx),'once');
        end


        % ################### set optodeSelectedVerts #####################
        
        function set.optodeSelectedVerts(app,vertIdx)
            app.optodeSelectedVerts = vertIdx;
            app.updateOptdePositionPatch('alpha');
        end


        % ################### set surfSelectedVerts ########################
        
        function set.hsurfSelectedVerts(app,vertIdx)
            app.hsurfSelectedVerts = vertIdx;
            app.updateHeadSurfPatch('alpha');
        end


        % ################ set hsurfRegisterResult #######################
        
        function set.hsurfRegisterResult(app,surf)
            if ~(isstruct(surf) && all(isfield(surf,{'headTmpl','hsurf'})) ...
                   && all(isfield(surf.headTmpl, {'faces','vertices'})) ...
                   && all(isfield(surf.hsurf,    {'faces','vertices'})))
                warning('OptodeReg:setHsurf:invalidValue', ...
                'Value must be a structure with fields ''headTmpl.faces'', ''headTmpl.vertices'', ''hsurf.faces'', and ''hsurf.vertices''!');
            end
            app.hsurfRegisterResult = surf;
        end


        % ################ set hsurfRegisterResult_ #######################
        
        function set.hsurfRegisterResult_(app,surf)
            app.hsurfRegisterResult = surf;
            try
                if   ~isempty(surf.headTmpl.faces) && ~isempty(surf.headTmpl.vertices) ...
                  && ~isempty(surf.hsurf.faces)    && ~isempty(surf.hsurf.vertices)
                    app.ShowSurfRegistrationResultsButton.Enable = true;
                else
                    app.ShowSurfRegistrationResultsButton.Enable = false;
                end
            catch
                app.ShowSurfRegistrationResultsButton.Enable = false;
            end
        end

    end


    %% =================== methods (Access = public) ======================

    methods (Access = public)
        

        function updateHeadModelPatch(app)
            ph = getappdata(app.UIAxes_orig, 'headModelPatch');
            ph.Vertices = app.headModelT.vertices;
        end


        % #################################################################


        function updateOptodeOrientationPatch(app,type)
            warning('TODO: app.updateOptodeOrientationPatch()');
        end


        % #################################################################


        function updateOptdePositionPatch(app,type)
            if nargin<2 || isempty(type), type = 'all'; end

            ph = getappdata(app.UIAxes_OptodeColor, 'headModelPatch');
            app.updatePatch(ph,type,app.optodeSelectedVerts);
        end


        % #################################################################


        function updateHeadSurfPatch(app,type)
            if nargin<2 || isempty(type), type = 'all'; end

            ph = getappdata(app.UIAxes_Surface,'headModelPatch');
            app.updatePatch(ph,type,app.hsurfSelectedVerts);
        end


        % #################################################################


        function updatePatch(app,ph,type,selVert)
            if ~iscell(type), type = {type}; end
            
            validTypes = {'all','vertices','color','faces','alpha'};
            type = cellfun(@(t)validatestring(t,validTypes),type,'UniformOutput',false);

            cellStrcmp = @(sc1,sc2)cellfun(@(s)any(strcmp(s,sc2)),sc1);
            % update vertices
            if any(cellStrcmp(type,{'all','vertices'}))
                ph.Vertices = app.headModelT.vertices;
            end
            
            % update colors
            if any(cellStrcmp(type,{'all','alpha'}))
%             keepFaceIdx = all(ismember(app.headModel.faces, find(app.optodeSelectedVerts)),2);
%             faces = app.headModel.faces(keepFaceIdx,:);
%             ph.Faces = removeisolatedsurf(app.headModel.vertices,faces,maxdiameter);
%             ph.FaceVertexCData = app.headModel.color;
%             ph.FaceVertexCData(~app.optodeSelectedVerts,:) = NaN;
                ph.FaceVertexAlphaData( selVert) = 1;
                ph.FaceVertexAlphaData(~selVert) = .02;
            end
        end

        % #################################################################


        function prepAxes(app,ah,action,setLight)
            if nargin < 2 || ~isa(ah,'matlab.graphics.axis.Axes')
                ah = app.UImainAxes; 
                %[app.UIAxes_orig, app.UIAxes_OptodeOrient, app.UIAxes_OptodeColor, app.UIAxes_Surface];
            end

            if nargin > 2 && strcmpi(action,'-reset')
                delete(findobj(ah,'Type','light'));
            elseif nargin > 2 && strcmpi(action,'-clear')
                arrayfun(@cla,ah);
            end

            axis(ah,'vis3d','equal');

            if nargin>3 && setLight
                delete(findall(ah,'Type','light'));
                lh(1,1) = lightangle(ah(1),-150,35);
                lh(1,2) = lightangle(ah(1),150,35);
                lh(1,3) = lightangle(ah(1),0,35);
                for ax = ah(2:end)
                    for lg = lh
                        copyobj(lg,ax);
                    end
                end
            end
        end

        
        % #################################################################


        function headTmpl = loadHeadTmpl(app,headTmplFile)
            try
                headTmpl = load(headTmplFile,'faces','vertices');
                headTmpl.sourceFile = headTmplFile;
            catch ME
                uialert(app.UIFigure, {'Failed to load reference head from', ...
                                        sprintf('''%s''',headTmplFile), ...
                                        ME.message}, ...
                        'Error loading reference head');
                error('OptodeRegApp:startup:failedToLoadRefHead',...
                        'Failed to load reference head because:\n%s',ME.message);
            end
        end


        % #################################################################


        function optPosMdl = loadOptodeTmpl(app,optPosFile)
            try
                optPosMdl = readtable(optPosFile);
                optPosMdl.Properties.VariableNames = {'Optode','x','y','z'};
%                 optPosMdl = table2struct(optPosMdl,'ToScalar',true);
            catch ME
                uialert(app.UIFigure, {'Failed to load optode position model from', ...
                                        sprintf('''%s''',optPosFile), ...
                                        ME.message}, ...
                        'Error loading optode position model');
                error('OptodeRegApp:startup:failedToLoadOptPosMdl',...
                        'Failed to load optode position model because:\n%s',ME.message);
            end
        end


        % #################################################################


        function loadHeadModel(app,headModelFile)
            try
                savedVars = load(headModelFile,'-mat');

                assert(isfield(savedVars,'headModel') && ...
                    all(isfield(savedVars.headModel,{'vertices','faces','color'})), ...
                    'OptRegApp:loadHeadModel:missingVarsInMatFile', ...
                    'The head model mat file \n %s\ndoes not contain the required data!',headModelFile);

                loadedHeadMdl = savedVars.headModel;

                % copy fields defined in the app's saveProperties cell
                iLoad = isfield(savedVars,app.saveProperties);
                for k = find(iLoad)
                    f = app.saveProperties{k};
                    app.(f) = savedVars.(f);
                end

                app.hmSaveFile = headModelFile;

            catch ME1
                if strcmpi(ME1.identifier,'OptRegApp:loadHeadModel:missingVarsInMatFile')
                    uialert(app.UIFigure,{'This does not seem to be a valid head model file!',headModelFile},'Failed to load head model');
                    throw(addCause(MException('OptodeReg:loadHeadMdl:inappropriateMatFile','Failed to load head model file ''%s''',headModelFile), ME1));
                else
                    try
                        h = uiprogressdlg(app.UIFigure, 'Value',0, 'Message','Loading ascii head model file...', ...
                            'Title','Please wait', 'Indeterminate','off');
%                         headModel = ft_read_headshape(headModelFile);
                        loadedHeadMdl = load_wavefront_obj(headModelFile,[],@(x)set(h,"Value",x));
                        loadedHeadMdl = cleanupPatch(loadedHeadMdl);
                    catch ME2
                        close(h);
                        msg = sprintf('Failed to load head model file ''%s''!\nBecause:\n%s',headModelFile,ME2.message);
                        uialert(app.UIFigure,msg,'Error loading model');
                        throw(addCause(MException('OptodeReg:loadHeadMdl:loadFailed','Failed to load head model file ''%s''',headModelFile), ME2));
                    end
                end
                % convert units to mm
                hmSize = max(range(loadedHeadMdl.vertices));
                if hmSize<.5,       scaleFact = 1000; % m
                elseif hmSize<5,    scaleFact = 100;  % dm
                elseif hmSize<50,   scaleFact = 10;   % cm
                else,               scaleFact = 1;    % mm
                end
                loadedHeadMdl.vertices = loadedHeadMdl.vertices .* scaleFact;
%                 headModel = ft_convert_units(headModel, 'mm');
%                 headModel = app.convertHeadModel_FTconvention(headModel);
                loadedHeadMdl.sourceFile = headModelFile;
                app.hmSaveFile = '';
                close(h);
            end

            % keep headModel fields that are defined in the class
            % property definition of the headModel
            hmFields = fieldnames(app.headModel).';
            iLoadHM = isfield(loadedHeadMdl,hmFields);
            for k = find(iLoadHM)
                f = hmFields{k};
                app.headModel.(f) = loadedHeadMdl.(f);
            end

            
            app.SaveHeadModelButton.Enable = "on";
            app.ReorientHeadModelButton.Enable = "on";
            if isfield(app.headModel,'isreoriented')
                app.setUI_hmReoriented(app.headModel.isreoriented);
            end
            if isfield(app.headModel,'hsruf_isregistered')
                app.setUI_hmRegistered(app.headModel.hsruf_isregistered);
            end
            if isfield(app.headModel,'optodes_isregistered')
                app.setUI_optRegistered(app.headModel.optodes_isregistered);
            end

            app.lastSlectedOptodeVert_ = NaN;
            app.lastSlectedSurfVert_ = NaN;

            app.UIFigure.Name = sprintf('%s - %s',app.FIGNAME,headModelFile);
            app.setHMmodified(false);
            
        end


        % #################################################################

        
        function dispHeadModel(app)
            ah = app.UIcolorSelectAxes;
            app.prepAxes(ah,'-clear');

            % create patch and copy to all axes
            ph = patch('Parent',       ah(1), ...
                       'Faces',         app.headModel.faces, ...
                       'Vertices',      app.headModelT.vertices, ...
                       'EdgeColor',     'none', ...
                       'FaceVertexCData',app.headModel.color, ...
                       'FaceVertexAlphaData',ones(size(app.headModel.vertices,1),1), ...
                       'FaceColor',     'interp', ...
                       'FaceAlpha',     'interp', ...
                       'AlphaDataMapping','none', ...
                       'FaceLighting',  'gouraud', ...
                       'ButtonDownFcn', @(h,evnt)app.cb_hmPatchColorPick(h,evnt), ...
                       'Tag',           'headModelPatch');
            setappdata(ah(1),'headModelPatch',ph);
            material(ph,'dull');

            for ax = ah(2:end).'
                ph = copyobj(ph,ax,'legacy');
                setappdata(ax,'headModelPatch',ph);
            end

            % update selection if available
            app.updateHeadModelPatch();
            app.updateOptdePositionPatch('alpha');
            app.updateHeadSurfPatch('alpha');
            app.updateOptodeOrientationPatch('alpha');
            
%             if isfield(app.headModel,'optodeSelectedSurf')
%                 app.optodeSelectedSurf = app.headModel.optodeSelectedSurf;
%             end
%             if isfield(app.headModel,'headSelectedSurf')
%                 app.hsurfSelectedSurf = app.headModel.headSelectedSurf;
%             end

        end
        

        % #################################################################

        
        function dispHeadTemplate(app)
            ah = app.UIAxes_OptodeOrient;
            app.prepAxes(ah,'-clear',true);

            % create patch and copy to all axes
            ph = patch('Parent',       ah(1), ...
                       'Faces',         app.hsurfRegisterResult.headTmpl.faces, ...
                       'Vertices',      app.hsurfRegisterResult.headTmpl.vertices, ...
                       'EdgeColor',     'none', ...
                       'FaceColor',     [1 0.8 0.7], ...
                       'FaceAlpha',     1, ...
                       'FaceLighting',  'gouraud', ...
                       'Tag',           'headTemplatePatch');
            setappdata(ah(1),'headTemplatePatch',ph);
            material(ph,'dull');
        end


        % #################################################################


        function runSetFiducialsUI(app)
            %

            try
                args = {app.headModelT, @(fids)app.setFiducials(fids,inv(app.headModel.tMat))};
                if app.hasFiducials, args{3} = app.headModelT.fiducials; end
                setFidsApp = SetFiducials(args{:});
            catch ME
                uialert(app.UIFigure,{'There was an error running the SetFiducial app, sorry.',ME.message},'SetFiducials failed');
                rethrow(ME);
            end
            uiwait(setFidsApp.UIFigure);

        end
        
        
        % #################################################################


        function setFiducials(app,fids,TM)
            fidNams = fieldnames(app.headModel.fiducials);
            if ~isstruct(fids) ||~all(isfield(fids,fidNams))
                uialert(app.UIFigure,'Fiducials must be a structure with fields ''Nasion'', ''RPA'' & ''LPA''!',...
                    'Invalid Fiducials');
                return;
            end
            if nargin>2 && ~isempty(TM)
                for k = 1:numel(fidNams)
                    fids.(fidNams{k}) = ApplyTM(TM,fids.(fidNams{k}));
                end
            end
            app.headModel.fiducials = fids;
            app.reorientHeadModel();
        end
        

        % #################################################################

        
        function reorientHeadModel(app)
        %
            
            if ~app.hasFiducials
                uialert(app.UIFigure,'Can not realign head model because fiducials are missing.',...
                    'Missing fiducials');
                return;
            end
            
            [fiducials, TM] = OptodeReg.registerFiducials(app.headModelT.fiducials,app.FIDUCIALS_MNI);
%             app.headModel.vertices = ApplyTM(TM,app.headModel.vertices);
%             app.headModel.fiducials = fiducials;
%             app.reorientTM = TM;
            app.headModel.tMat = (app.headModel.tMat.' * TM.').';
            
            app.headModel.isreoriented = true;
            app.setUI_hmReoriented(true);
            
            app.updateHeadModelPatch();
            app.updateOptdePositionPatch('vert');
            app.updateHeadSurfPatch('vert');
            app.updateOptodeOrientationPatch('vert');
            
        end


        % #################################################################


        function registerHeadSurf(app)
            prgrh = uiprogressdlg(app.UIFigure,"Indeterminate","on", ...
                'Title','Running registration', ...
                "Message",'Registering selected head surface to template...');


            try
                headTmpl = app.loadHeadTmpl(app.headTmplFile);
                tmplPts = headTmpl.vertices;
                dataSurf = app.hsurfSelectedSurfT;
            
                dataSurf.faces = removeisolatedsurf(dataSurf.vertices,dataSurf.faces,app.isolatedSurf_MinDiameter);
                [dataSurf.vertices,dataSurf.faces] = removeisolatednode(dataSurf.vertices,dataSurf.faces);
                [dataSurf.vertices,TM] = OptodeReg.registerSurfs(tmplPts,dataSurf.vertices,app.hsurfRegisterMethod{:});
                
                app.headModel.tMat = (app.headModel.tMat.' * TM.').';
%                 app.headModel.vertices = ApplyTM(TM,app.headModel.vertices);
%                 ff = fieldnames(app.headModel.fiducials);
%                 for k = 1:numel(ff)
%                     app.headModel.fiducials.(ff{k}) = ApplyTM(TM,app.headModel.fiducials.(ff{k}));
%                 end
            
            catch ME
                close(prgrh);
                uialert(app.UIFigure,{'Head surface registration failed because' ...
                                      ME.message}, ...
                        'Surface registration failed');
                rethrow(ME);
            end

            app.headModel.hsruf_isregistered = true;
            app.setUI_hmRegistered(true);

            app.updateHeadModelPatch();
            app.updateOptdePositionPatch('vert');
            app.updateHeadSurfPatch('vert');
            app.dispHeadTemplate();
%             app.updateOptodeOrientationPatch('vert');
            close(prgrh);

            app.hsurfRegisterResult_ = struct('hsurf',dataSurf,'headTmpl',headTmpl);
        end


        % #################################################################


        function registerOptodes(app)
            prgrh = uiprogressdlg(app.UIFigure,"Indeterminate","off", ...
                'Title','Running registration', ...
                "Message",'Detecting optode positions...');

            try
                % load optode position template
                prgrh.Value = .05;
                opdPosTmpl = app.loadOptodeTmpl(app.optodeTmplFile);
                opdPosTmpl = opdPosTmpl(startsWith(opdPosTmpl.Optode,{'S','D'}),:);
                xyzTmpl = opdPosTmpl{:,{'x','y','z'}};
                hsTmpl = app.hsurfRegisterResult.headTmpl;
                prgrh.Value = .1;

                % prepare selected surf (remove unconnected vertices and
                % small isolated faces.
                dataSurf = app.optodeSelectedSurfT;
                dataSurf.faces = removeisolatedsurf(dataSurf.vertices,dataSurf.faces,app.isolatedOpt_MinDiameter);
                prgrh.Value = .2;
                [dataSurf.vertices,dataSurf.faces] = removeisolatednode(dataSurf.vertices,dataSurf.faces);
                prgrh.Value = .3;

                % split selected optode surface into separate surfs
                [splitSurfs,nVerts] = clusterSurf(dataSurf,5);
                keep = nVerts>5;
                splitSurfs = splitSurfs(keep);
                prgrh.Value = .4;

                if isempty(splitSurfs)
                    msg = sprintf('No optode surface remained after removing %d small clusters of vertices.',sum(~keep));
                    uialert(app.UIFigure,msg,'No Optode surface available');
                    error('OptodeReg:registerOptodes:noSurface',msg);
                end

                prgrh.Message = 'Matching optode positions...';

                % get incenters & normals of optode surface
                incenters = arrayfun(@(x)mean(x.vertices,1),splitSurfs,'UniformOutput',false);
                incenters = vertcat(incenters{:});

                normals = arrayfun(@(x)affine_fit(x.vertices),splitSurfs,'UniformOutput',false);
                normals = [normals{:}].';

                % create convex hull of head
                [cHull,v1,v2,v3] = mkConvexHull(hsTmpl);
                
                prgrh.Value = .45;

                % find intersections of optode normals and convex hull
                optPos = nan(numel(splitSurfs),3);
                optVertsProj = cell(numel(splitSurfs),1);
                p = linspace(prgrh.Value,.6,numel(splitSurfs));
                for iOpt = 1:numel(splitSurfs)
                    [isInters,d,~,~,intersectXYZ] = TriangleRayIntersection(incenters(iOpt,:),normals(iOpt,:),...
                        v1,v2,v3,'lineType','line','fullReturn',true);
                    if ~any(isInters), continue; end
                    idxInters = find(isInters);
                    [~,idxMin] = min(abs(d(idxInters)));
                    optPos(iOpt,:) = intersectXYZ(idxInters(idxMin),:);

                    if app.useSelVerts4optRegistration
                        nvert = size(splitSurfs(iOpt).vertices,1);
                        optVertsProj{iOpt} = nan(nvert,3);
                        for iv = 1:nvert
                            [isInters,d,~,~,intersectXYZ] = TriangleRayIntersection(splitSurfs(iOpt).vertices(iv,:),normals(iOpt,:),...
                                v1,v2,v3,'lineType','line','fullReturn',true);
                            if ~any(isInters), continue; end
                            idxInters = find(isInters);
                            [~,idxMin] = min(abs(d(idxInters)));
                            optVertsProj{iOpt}(iv,:) = intersectXYZ(idxInters(idxMin),:);
                        end
                        iFail = any(isnan(optVertsProj{iOpt}),2);
                        optVertsProj{iOpt} = optVertsProj{iOpt}(~iFail,:);
                    end

                    prgrh.Value = p(iOpt);
                end

                optProjectFailed = any(isnan(optPos),2);
                optPos = optPos(~optProjectFailed,:);
                optOrigins = incenters(~optProjectFailed,:);

                % ICP to move optode pos model to reconstructed positions
                if app.useSelVerts4optRegistration
                    optVertsProj = vertcat(optVertsProj{:});
                    [xyzTmpl_T,TM] = OptodeReg.registerSurfs(optVertsProj, xyzTmpl, app.optodeRegisterMethod{:});
                else
                    [xyzTmpl_T,TM] = OptodeReg.registerSurfs(optPos,       xyzTmpl, app.optodeRegisterMethod{:});
                end
                hsTmpl_T = hsTmpl;
                hsTmpl_T.vertices = ApplyTM(TM,hsTmpl.vertices);
                prgrh.Value = .8;

                [modl_optodeIDs,unmtchTmpl,unmtchModl] = OptodeReg.matchOptodes(xyzTmpl_T, optPos, opdPosTmpl.Optode, app.optodeMatchingCost);

                % run the optode revision GUI showing opt positions,
                % orientations (normals) and assingments
                % --->>>> TODO: Better: only update optode orientation axis
                % here and run the revision tool from a seperate button
                % (currently 'Align Optodes'). Use the registrationResults
                % property for the results (here and from revision tool?)
                % and use a PostSet listener to update axis...? <<<<<-----
                [optTmplNormals] = getOptodeTangentPlane(xyzTmpl_T,cHull.vertices,cHull.faces,true,true);
                
                prgrh.Message = 'Running Revision GUI...';
                prgrh.Value = .9;
                h = ReviewOptodeIDs(xyzTmpl_T, ...+optTmplNormals.*3, ...
                                   cat(3,optPos,optOrigins), ...
                                   opdPosTmpl.Optode, ...
                                   modl_optodeIDs, ...
                                   hsTmpl, ...
                                   app.headModelT, ...
                                   [],[],[],[],...
                                   'propReturnFnc', @(x)setappdata(app.RegisterOptodesButton,'RegisterResults',x));
                
                % return normals + incenters or model-normals & -positions
                % for all model optodes


            catch ME
                close(prgrh);
                uialert(app.UIFigure,{'Optode registration failed because' ...
                                      ME.message}, ...
                        'Optode registration failed');
                rethrow(ME);
            end

        end


        % #################################################################


        function suc = saveHeadModel(app,file)
            if nargin < 2, file = app.hmSaveFile; end
            if isempty(file), suc = app.saveHeadModelUI(); return; end
            savevar.headModel = app.headModel;
            for k = 1:numel(app.saveProperties)
                f = app.saveProperties{k};
                savevar.(f) = app.(f);
            end
            save(file,'-struct','savevar');
            app.hmSaveFile = file;
            suc = true;
            app.UIFigure.Name = sprintf('%s - %s',app.FIGNAME,file);
            app.setHMmodified(false);
        end
    end


    %% =================== methods (Access = protected) ===================

    methods (Access = protected)
        function setUI_hmReoriented(app,isreor)
            if isreor
                app.ReorientHeadModelButton.BackgroundColor = [122, 255, 155]./255;
                app.ReorientHeadModelButton.Tooltip = 'Head model has already been reoriented';
                app.RegisterSurfaceButton.Enable = 'on';
            else
                app.ReorientHeadModelButton.BackgroundColor = [255, 62, 101]./255;
                app.ReorientHeadModelButton.Tooltip = 'Head model has not been reoriented';
                app.RegisterSurfaceButton.Enable = 'off';
            end
        end

        function setUI_hmRegistered(app,isreg)
            if isreg
                app.RegisterSurfaceButton.BackgroundColor = [122, 255, 155]./255;
                app.RegisterSurfaceButton.Tooltip = 'Head surface has been registered to template';
                app.RegisterOptodesButton.Enable = 'on';
                app.ShowSurfRegistrationResultsButton.Enable = 'on';
            else
                app.RegisterSurfaceButton.BackgroundColor = [255, 62, 101]./255;
                app.RegisterSurfaceButton.Tooltip = 'Head surface has not been registered to template';
                app.RegisterOptodesButton.Enable = 'off';
                app.ShowSurfRegistrationResultsButton.Enable = 'off';
            end
        end

        function setUI_optRegistered(app,isreg)
            if isreg
                app.RegisterOptodesButton.BackgroundColor = [122, 255, 155]./255;
                app.RegisterOptodesButton.Tooltip = 'Optodes have been registered to model';
            else
                app.RegisterOptodesButton.BackgroundColor = [255, 62, 101]./255;
                app.RegisterOptodesButton.Tooltip = 'Optodes have not been registered to model';
            end
        end

        function setUI_OptodeTemplateList(app)
            fs = dir(fullfile(app.ROOT_PATH,app.optodeTmplDir,'*.*'));
            fs = fs(~[fs.isdir]);
            app.OptodeTemplateDropDown.Items = {fs.name};
        end

        function setUI_HeadTemplateList(app)
            fs = dir(fullfile(app.ROOT_PATH,app.headTmplDir,'*.*'));
            fs = fs(~[fs.isdir]);
            app.HeadTemplateDropDown.Items = {fs.name};
        end
    end


    %% =================== methods (Access = private) =====================

    methods (Access = private)


        function cb_hmPatchColorPick(app,ph,evnt)
            switch evnt.Source.Parent
                case app.UIAxes_orig
                    selectedTab = app.RegModeTabGroup.SelectedTab;
                case app.UIAxes_OptodeColor
                    selectedTab = app.OptodesTab;
                case app.UIAxes_Surface
                    selectedTab = app.SurfaceTab;
                otherwise, error('BUG');
            end
            app.RegModeTabGroup.SelectedTab = selectedTab;

            % find closest vertex
            delta = bsxfun(@minus, ph.Vertices, evnt.IntersectionPoint);
            dist = sqrt(sum(delta.^2,2));
            [~,clickedVert] = min(dist);

            switch selectedTab
                case app.OptodesTab
                    simThresh = app.OptodeColorThresholdSlider.Value;
                    app.updateColorSelection(clickedVert,simThresh,'optodes');
%                     app.lastSlectedOptodeVert_ = clickedVert;

                case app.SurfaceTab
                    simThresh = app.SurfaceColorThresholdSlider.Value;
                    app.updateColorSelection(clickedVert,simThresh,'headsurf');
%                     app.lastSlectedSurfVert_ = clickedVert;

                otherwise, error('BUG');
            end

        end


        % #################################################################


        function updateColorSelection(app,clickedVert,simThresh,vertGroup)
            selVerts = findSimilarColor(app.headModel.color, app.headModel.color(clickedVert,:), simThresh);
            app.updateVertSelection(clickedVert,selVerts,vertGroup);
        end


        % #################################################################


        function updateVertSelection(app, clickedVert, selVerts, vertGroup)

            % check which vertex group to be updated (optodes/headsurf)
            vertGroup = validatestring(vertGroup,{'optodes','headsurf'});
            switch vertGroup
                case 'optodes'
                    selMode = app.OptodeIdentificationButtonGroup.SelectedObject;
                    currentSelVerts = app.optodeSelectedVerts;
                    contigRegion = app.OptodeContigRegionsCheckBox.Value;
                case 'headsurf'
                    selMode = app.SurfaceIdentificationButtonGroup.SelectedObject;
                    currentSelVerts = app.hsurfSelectedVerts;
                    contigRegion = app.SurfaceContigRegionsCheckBox.Value;
            end


            % get new selection based on selected mode (new/add/subtract)
            setLastSelVert = false; % TODO: live threshold adjustment does not work properly for add and subtract (we would need to always use the same 'currentSelVerts'!)
            switch selMode.Tag
                case 'newSelection'
                    currentSelVerts = selVerts;
                    setLastSelVert = true;

                case 'addSelection'
                    if contigRegion
                        selVerts = selectContiguousPatch(app.headModel.faces,selVerts,clickedVert);
                    end
                    currentSelVerts = or(currentSelVerts, selVerts);
                
                case 'subtractSelection'
                    if contigRegion
                        selVerts = selectContiguousPatch(app.headModel.faces,selVerts,clickedVert);
                    end
                    currentSelVerts = and(currentSelVerts, ~selVerts);

               otherwise, error('BUG');
            end


            % set new selection to app property
            switch vertGroup
                case 'optodes'
                    app.optodeSelectedVerts = currentSelVerts;
                    if setLastSelVert, app.lastSlectedOptodeVert_ = clickedVert; end
                case 'headsurf'
                    app.hsurfSelectedVerts = currentSelVerts;
                    if setLastSelVert, app.lastSlectedSurfVert_ = clickedVert; end
            end

        end
        

        % #################################################################


        function storeUIpropConfig(app, source, property, value)
            % find object name
            p = properties(app);
            k = cellfun(@(x)isequal(app.(x),source),p);
            if sum(k)~=1
                warning('OptRegApp:storeUIpropConfig:ObjectNotFound', ...
                    'Could not store UIproperty in app config. Callback source not found in app properties!');
                return;
            end
            app.config.UIproperties.(p{k}).(property) = value;
        end
        

        % #################################################################


        function restoreUIpropConfig(app)
            obj = fieldnames(app.config.UIproperties);
            for io = 1:numel(obj)
                o = obj{io};
                props = fieldnames(app.config.UIproperties.(o));
                for ip = 1:numel(props)
                    p = props{ip};
                    try
                        app.(o).(p) = app.config.UIproperties.(o).(p);
                    catch ME
                        app.config.UIproperties.(o) = rmfield(app.config.UIproperties.(o),p);
                        warning('OptodeReg:restoreUIprop:failedToSetProp',...
                            'Failed to restore property value for %s.%s because:\n%s',o,p,ME.message);
                    end
                end
            end
        end
        

        % #################################################################


%         function pth = selectFTpath(app,pth)
%             valid = app.checkFTpath(pth);
%             retry = true;
%             while ~valid && retry
%                 pth = uigetdir(pth,'Select path to fieldtrip toolbox (ft_defaults.m)');
%                 valid = app.checkFTpath(pth);
%                 if ~valid
%                     pth = '';
%                     sel = uiconfirm(app.UIFigure,{'Selected path does not contain the requires ft_defaults.m','Retry?'},...
%                         'Invalid Fieldtrip path', ...
%                         'Options',       {'Retry','Cancel'}, ...
%                         'DefaultOption', 1, ...
%                         'CancelOption',  2);
%                     retry = strcmpi(sel,'retry');
%                 end
%             end
%             
%         end


        % #################################################################


        function setHMmodified(app,v)
            app.hmModified = v;
            if v
                app.UIFigure.Name = regexprep(app.UIFigure.Name,'(.)\**$','$1*','once');
            else
                app.UIFigure.Name = regexprep(app.UIFigure.Name,'\**$','','once');
            end
        end


        % #################################################################


        function suc = saveHeadModelUI(app,~,~)
            suc = false;
            if ~isempty(app.hmSaveFile)
                pth = app.hmSaveFile;
            else
                pth = app.config.lastHeadModelSavePath;
                if ~exist(pth,'dir') && isfield(app.headModel,'sourceFile')
                    pth = fileparts(app.headModel.sourceFile);
                end
            end
            [file,pth] = uiputfile('*.mat','Save head model',pth);
            if file==0, return; end
            app.config.lastHeadModelSavePath = pth;

            try
                suc = app.saveHeadModel(fullfile(pth,file));
            catch ME
                uialert(app.UIFigure,{'Could not save file because:',ME.message},'Error saving head model');
                rethrow(ME);
            end
        end
    end


    %% =================== methods (Static) ===============================

    methods (Static)
%         [hm,addFields] = convertHeadModel_FTconvention(hm,outFormat,addFields);
        [registerdFids, TM] = registerFiducials(fiducials,referenceFids)
        [dataHead,TM] = registerSurfs(modlVerts,dataVerts,method,varargin)
        [idModl,unmtchTmpl,unmtchModl] = matchOptodes(xyzTmpl, xyzModl, idTmpl, matchCost);
        h = showSurfRegResults(headTmpl,dataSurf)
        mustBeProperty(p,cn);

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, headModel)
            addpath(app.ROOT_PATH);
            addpath(fullfile(app.ROOT_PATH,'utils'));
            addpath(fullfile(app.ROOT_PATH,'utils','external'));

            app.setUI_HeadTemplateList();
            app.setUI_OptodeTemplateList();

            % add modified listener
            modListnCB = @(~,~)app.setHMmodified(true);
            addlistener(app,[{'headModel'},app.saveProperties],'PostSet',modListnCB);

            % load cfg
            if exist(app.CFG_FILE,'file')
                cfgVars = fieldnames(app.config).';
                cfgFromFile = load(app.CFG_FILE);
                idxOverwrite = ismember(cfgVars,fieldnames(cfgFromFile));
                keepVars = cfgVars(idxOverwrite);
                for k = 1:numel(keepVars)
                    app.config.(keepVars{k}) = cfgFromFile.(keepVars{k});
                end
                app.restoreUIpropConfig();
            end

            % setup fieldtrip
%             app.config.FT_PATH = app.selectFTpath(app.config.FT_PATH);
%             addpath(app.config.FT_PATH);
%             if ~exist('ft_defaults.m','file')
%                 msg = 'Fieldtrip toolbox not found but required for loading and reorienting the head model.';%, ...
% %                     app.config.FT_PATH);
%                 uialert(app.UIFigure, msg, 'Fieldtrip not found');
%                 warning('OptRegApp:startUp:fieldtripNotFound',msg);
%             else
%                 global ft_default %#ok
%                 ft_default.trackusage = 'no';
%                 ft_default.showlogo = 'no';
%                 ft_defaults;
%             end

            % setup axes
%             app.prepAxes();
            Link = linkprop(app.UImainAxes,{'CameraUpVector', 'CameraPosition', ...
                'CameraTarget', 'XLim', 'YLim', 'ZLim'});
            setappdata(app.UImainAxes(1).Parent, 'StoreTheLink', Link);

            % load head model if provided
            if nargin > 1 && ~isempty(headModel)
                app.loadHeadModel(headModel);
                app.dispHeadModel();
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.hmModified
                resp = uiconfirm(app.UIFigure,{'Do you want to save the head model before closing the app?','Changes will be lost!'},...
                    'Save changes?','Options',{'yes','no','cancel'},'DefaultOption','yes','CancelOption','cancel');
                switch lower(resp)
                    case 'cancel', return;
                    case 'yes'
                        suc = app.saveHeadModelUI();
                        if ~suc, return; end
                    case 'no' % pass
                    otherwise, warning('Bug in UIFigureCloseRequest');
                end
            end
            try
                cfg = app.config;
                save(app.CFG_FILE,'-struct','cfg');
            catch ME
                warning(ME.identifier,'Failed to save configuration because\n%s',ME.message);
            end
            delete(app)
        end

        % Callback function: LoadHeadModelButton, LoadHeadModelMenu
        function cb_LoadHeadModelButton(app, event)
            [file,path] = uigetfile({'*.obj;*.mat'}, 'Load Head Model', ...
                app.config.lastHeadModelLoadPath, 'MultiSelect','off');
            if file==0
                return;
            end
            
            app.config.lastHeadModelLoadPath = path;
            app.loadHeadModel(fullfile(path,file));

            app.dispHeadModel();

        end

        % Selection change function: RegModeTabGroup
        function cb_RegModeChanged(app, event)
%             selectedTab = app.RegModeTabGroup.SelectedTab;
            
        end

        % Value changed function: HeadTemplateDropDown, 
        % ...and 5 other components
        function cb_storeUIchangedValue(app, event)
            value = event.Source.Value;
            app.storeUIpropConfig(event.Source,'Value',value);
        end

        % Selection changed function: SurfaceIdentificationButtonGroup
        function cb_SurfIdentBtnGrpSelChanged(app, event)
%             selectedButton = app.SurfaceIdentificationButtonGroup.SelectedObject;
            app.lastSlectedSurfVert_ = NaN;
        end

        % Selection changed function: OptodeIdentificationButtonGroup
        function cb_OptodeIdentBtnGrpSelChanged(app, event)
%             selectedButton = app.OptodeIdentificationButtonGroup.SelectedObject;
            app.lastSlectedOptodeVert_ = NaN;
        end

        % Value changing function: SurfaceColorThresholdSlider
        function cb_SurfaceColorThresholdChanging(app, event)
            if isnan(app.lastSlectedSurfVert), return; end
            updateColorSelection(app, app.lastSlectedSurfVert, event.Value, 'headsurf');
        end

        % Value changing function: OptodeColorThresholdSlider
        function cb_OptodeColorThresholdChanging(app, event)
%             fprintf('%f %f\n',app.OptodeColorThresholdSlider.Value,event.Value);
            if isnan(app.lastSlectedOptodeVert), return; end
            updateColorSelection(app, app.lastSlectedOptodeVert, event.Value, 'optodes');
        end

        % Button pushed function: ReorientHeadModelButton
        function cb_ReorientHeadModelButton(app, event)
            if app.headModel.isreoriented
                resp = uiconfirm(app.UIFigure,'Head model seems to be reoriented already. Repeat?', ...
                    'Repeat reorientation', ...
                    'Options',       {'yes','no'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption',  2);
                if strcmpi(resp,'no'), return; end
            end
            app.runSetFiducialsUI();

        end

        % Callback function: SaveHeadModelButton, SaveMenu, SaveasMenu
        function cb_SaveHeadModelButton(app, event)
            if event.Source == app.SaveMenu
                app.saveHeadModel();
            else
                app.saveHeadModelUI();
            end
        end

        % Drop down opening function: HeadTemplateDropDown, 
        % ...and 1 other component
        function cb_updateDropDownList(app, event)
            switch event.Source
                case app.OptodeTemplateDropDown
                    app.setUI_OptodeTemplateList()
                case app.HeadTemplateDropDown
                    app.setUI_HeadTemplateList()
                otherwise, error('BUG');
            end
        end

        % Callback function
        function cb_updateTmplDropDownValue(app, event)
            switch event.Source
                case app.OptodeTemplateDropDown
                    app.loadOptodeTemplate();
                case app.HeadTemplateDropDown
                    app.loadHeadTemplate();
                otherwise, error('BUG');
            end
            app.cb_storeUIchangedValue(event);
        end

        % Button pushed function: RegisterSurfaceButton
        function cb_RegisterHSurfButton(app, event)
            if app.headModel.hsruf_isregistered
                resp = uiconfirm(app.UIFigure,'Head surface seems to be registered already. Repeat?', ...
                    'Repeat surface registration', ...
                    'Options',       {'yes','no'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption',  2);
                if strcmpi(resp,'no'), return; end
            end
            app.registerHeadSurf();
            h = OptodeReg.showSurfRegResults(app.hsurfRegisterResult.headTmpl,app.hsurfRegisterResult.hsurf);
            app.RegModeTabGroup.SelectedTab = app.OptodesTab;
        end

        % Button pushed function: ShowSurfRegistrationResultsButton
        function cb_ShowSurfRegResultsButton(app, event)
            h = OptodeReg.showSurfRegResults(app.hsurfRegisterResult.headTmpl,app.hsurfRegisterResult.hsurf);
        end

        % Button pushed function: RegisterOptodesButton
        function cb_RegisterOptodesButton(app, event)
            if app.headModel.optodes_isregistered
                resp = uiconfirm(app.UIFigure,'Optodes seem to be registered already. Repeat?', ...
                    'Repeat optodes registration', ...
                    'Options',       {'yes','no'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption',  2);
                if strcmpi(resp,'no'), return; end
            end
            app.registerOptodes();
            ...todo
        end

        % Button pushed function: AlignOptodesButton
        function cb_AlignOptodesButton(app, event)
            opdPosTmpl = app.loadOptodeTmpl(app.optodeTmplFile);
            tmplOpt = struct('ID',opdPosTmpl{:,'Optode'},'xyz',opdPosTmpl{:,{'x','y','z'}});
            args = {'tmpl_headSurface',     app.hsurfRegisterResult.headTmpl, ...
                    'modl_headSurface',     app.headModelT, ...
                    'tmpl_optodePositions', tmplOpt, ...
                    'modl_optodePositions', tmplOpt};
            h = ReviewOptodeIDs(args{:});
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
%             key = event.Key;
%             if strcmpi(key,'s') && all(strcmpi(event.Modifier,'control'))
%                 app.saveHeadModel();
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Optode Registration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.Tag = 'OptodeRegFig';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = '&File';
            app.LoadHeadModelMenu = uimenu(app.FileMenu);
            app.LoadHeadModelMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_LoadHeadModelButton, true);
            app.LoadHeadModelMenu.Accelerator = 'L';
            app.LoadHeadModelMenu.Text = '&Load Head Model';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_SaveHeadModelButton, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = '&Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_SaveHeadModelButton, true);
            app.SaveasMenu.Text = 'Save as...';
            app.AxesPanel = uipanel(app.UIFigure);
            app.UIAxes_Surface = uiaxes(app.AxesPanel);
            xlabel(app.UIAxes_Surface, 'X')
            ylabel(app.UIAxes_Surface, 'Y')
            zlabel(app.UIAxes_Surface, 'Z')
            app.UIAxes_OptodeOrient = uiaxes(app.AxesPanel);
            xlabel(app.UIAxes_OptodeOrient, 'X')
            ylabel(app.UIAxes_OptodeOrient, 'Y')
            zlabel(app.UIAxes_OptodeOrient, 'Z')
            app.UIAxes_OptodeColor = uiaxes(app.AxesPanel);
            xlabel(app.UIAxes_OptodeColor, 'X')
            ylabel(app.UIAxes_OptodeColor, 'Y')
            zlabel(app.UIAxes_OptodeColor, 'Z')
            app.UIAxes_orig = uiaxes(app.AxesPanel);
            app.UIAxes_orig.XTick = [];
            app.UIAxes_orig.YTick = [];
            app.UIAxes_orig.ZTick = [];
            app.AxLabel_Surface = uilabel(app.AxesPanel);
            app.AxLabel_Surface.Text = 'Select Head Surface';
            app.AxLabel_OptodeOrient = uilabel(app.AxesPanel);
            app.AxLabel_OptodeOrient.Text = 'Optode Orientation';
            app.AxLabel_OptodeColor = uilabel(app.AxesPanel);
            app.AxLabel_OptodeColor.Text = 'Select Optode Color Stickers';
            app.AxLabel_orig = uilabel(app.AxesPanel);
            app.AxLabel_orig.Text = 'Head Model';
            app.LoadHeadModelButton = uibutton(app.UIFigure, 'push');
            app.LoadHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_LoadHeadModelButton, true);
            app.LoadHeadModelButton.Text = 'Load Head Model';
            app.ReorientHeadModelButton = uibutton(app.UIFigure, 'push');
            app.ReorientHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_ReorientHeadModelButton, true);
            app.ReorientHeadModelButton.Enable = 'off';
            app.ReorientHeadModelButton.Text = 'Reorient Head Model';
            app.RegModeTabGroup = uitabgroup(app.UIFigure);
            app.RegModeTabGroup.SelectionChangedFcn = createCallbackFcn(app, @cb_RegModeChanged, true);
            app.SurfaceTab = uitab(app.RegModeTabGroup);
            app.SurfaceTab.Title = 'Head Surface';
            app.SurfaceIdentificationButtonGroup = uibuttongroup(app.SurfaceTab);
            app.SurfaceIdentificationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @cb_SurfIdentBtnGrpSelChanged, true);
            app.SurfaceIdentificationButtonGroup.Title = 'Head Surface Identification';
            app.SurfaceNewSelectionButton = uitogglebutton(app.SurfaceIdentificationButtonGroup);
            app.SurfaceNewSelectionButton.Tag = 'newSelection';
            app.SurfaceNewSelectionButton.Text = 'New Selection';
            app.SurfaceNewSelectionButton.Value = true;
            app.SurfaceAddtoSelectionButton = uitogglebutton(app.SurfaceIdentificationButtonGroup);
            app.SurfaceAddtoSelectionButton.Tag = 'addSelection';
            app.SurfaceAddtoSelectionButton.Text = '+ Add to Selection';
            app.SurfaceSubtractfromSelectionButton = uitogglebutton(app.SurfaceIdentificationButtonGroup);
            app.SurfaceSubtractfromSelectionButton.Tag = 'subtractSelection';
            app.SurfaceSubtractfromSelectionButton.Text = '- Subtract from Selection';
            app.SurfaceColorThresholdSliderLabel = uilabel(app.SurfaceIdentificationButtonGroup);
            app.SurfaceColorThresholdSliderLabel.Text = 'Color similarity threshold';
            app.SurfaceColorThresholdSlider = uislider(app.SurfaceIdentificationButtonGroup);
            app.SurfaceColorThresholdSlider.Limits = [0 1];
            app.SurfaceColorThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.SurfaceColorThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.SurfaceColorThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @cb_SurfaceColorThresholdChanging, true);
            app.SurfaceColorThresholdSlider.MinorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.SurfaceColorThresholdSlider.Value = 0.1;
            app.SurfaceContigRegionsCheckBox = uicheckbox(app.SurfaceIdentificationButtonGroup);
            app.SurfaceContigRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.SurfaceContigRegionsCheckBox.Tooltip = {'Only select vertices connected to the clicked vertex. '; 'Ignored for ''New Selection'''};
            app.SurfaceContigRegionsCheckBox.Text = 'only contiguous regions';
            app.RegisterSurfaceButton = uibutton(app.SurfaceTab, 'push');
            app.RegisterSurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @cb_RegisterHSurfButton, true);
            app.RegisterSurfaceButton.Enable = 'off';
            app.RegisterSurfaceButton.Text = 'Register Head Surface';
            app.SelectHeadTemplateDropDownLabel = uilabel(app.SurfaceTab);
            app.SelectHeadTemplateDropDownLabel.Text = 'Select Head Template';
            app.HeadTemplateDropDown = uidropdown(app.SurfaceTab);
            app.HeadTemplateDropDown.Items = {};
            app.HeadTemplateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @cb_updateDropDownList, true);
            app.HeadTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.HeadTemplateDropDown.Value = {};
            app.ShowSurfRegistrationResultsButton = uibutton(app.SurfaceTab, 'push');
            app.ShowSurfRegistrationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @cb_ShowSurfRegResultsButton, true);
            app.ShowSurfRegistrationResultsButton.Enable = 'off';
            app.ShowSurfRegistrationResultsButton.Text = 'Show Registration Result';
            app.OptodesTab = uitab(app.RegModeTabGroup);
            app.OptodesTab.Title = 'Optodes';
            app.OptodeIdentificationButtonGroup = uibuttongroup(app.OptodesTab);
            app.OptodeIdentificationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @cb_OptodeIdentBtnGrpSelChanged, true);
            app.OptodeIdentificationButtonGroup.Title = 'Optode Identification';
            app.OptodeNewSelectionButton = uitogglebutton(app.OptodeIdentificationButtonGroup);
            app.OptodeNewSelectionButton.Tag = 'newSelection';
            app.OptodeNewSelectionButton.Text = 'New Selection';
            app.OptodeNewSelectionButton.Value = true;
            app.OptodeAddtoSelectionButton = uitogglebutton(app.OptodeIdentificationButtonGroup);
            app.OptodeAddtoSelectionButton.Tag = 'addSelection';
            app.OptodeAddtoSelectionButton.Text = '+ Add to Selection';
            app.OptodeSubtractfromSelectionButton = uitogglebutton(app.OptodeIdentificationButtonGroup);
            app.OptodeSubtractfromSelectionButton.Tag = 'subtractSelection';
            app.OptodeSubtractfromSelectionButton.Text = '- Subtract from Selection';
            app.OptodeColorThresholdSliderLabel = uilabel(app.OptodeIdentificationButtonGroup);
            app.OptodeColorThresholdSliderLabel.Text = 'Color similarity threshold';
            app.OptodeColorThresholdSlider = uislider(app.OptodeIdentificationButtonGroup);
            app.OptodeColorThresholdSlider.Limits = [0 1];
            app.OptodeColorThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.OptodeColorThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.OptodeColorThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @cb_OptodeColorThresholdChanging, true);
            app.OptodeColorThresholdSlider.MinorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.OptodeColorThresholdSlider.Value = 0.1;
            app.OptodeContigRegionsCheckBox = uicheckbox(app.OptodeIdentificationButtonGroup);
            app.OptodeContigRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.OptodeContigRegionsCheckBox.Tooltip = {'Only select vertices connected to the clicked vertex. '; 'Ignored for ''New Selection'''};
            app.OptodeContigRegionsCheckBox.Text = 'only contiguous regions';
            app.RegisterOptodesButton = uibutton(app.OptodesTab, 'push');
            app.RegisterOptodesButton.ButtonPushedFcn = createCallbackFcn(app, @cb_RegisterOptodesButton, true);
            app.RegisterOptodesButton.Enable = 'off';
            app.RegisterOptodesButton.Text = 'Register Optodes';
            app.SelectOptodeTemplateDropDownLabel = uilabel(app.OptodesTab);
            app.SelectOptodeTemplateDropDownLabel.Text = 'Select Optode Template';
            app.OptodeTemplateDropDown = uidropdown(app.OptodesTab);
            app.OptodeTemplateDropDown.Items = {};
            app.OptodeTemplateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @cb_updateDropDownList, true);
            app.OptodeTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @cb_storeUIchangedValue, true);
            app.OptodeTemplateDropDown.Value = {};
            app.AlignOptodesButton = uibutton(app.OptodesTab, 'push');
            app.AlignOptodesButton.ButtonPushedFcn = createCallbackFcn(app, @cb_AlignOptodesButton, true);
            app.AlignOptodesButton.Enable = 'off';
            app.AlignOptodesButton.Text = 'Align Optodes';
            app.SaveHeadModelButton = uibutton(app.UIFigure, 'push');
            app.SaveHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @cb_SaveHeadModelButton, true);
            app.SaveHeadModelButton.Text = 'Save Head Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptodeReg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end