classdef ReviewOptodeIDs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReviewOptodeAssignmentsUIFigure  matlab.ui.Figure
        EditMenu                        matlab.ui.container.Menu
        ResetalignmentMenu              matlab.ui.container.Menu
        RestoreremovedoptodesMenu       matlab.ui.container.Menu
        DeleteextrapolatedoptodesMenu   matlab.ui.container.Menu
        AxesPanel                       matlab.ui.container.Panel
        TemplateAxisTitleLabel          matlab.ui.control.Label
        ModelAxisTitleLabel             matlab.ui.control.Label
        UIAxes_tmpl                     matlab.ui.control.UIAxes
        UIAxes_modl                     matlab.ui.control.UIAxes
        CtrPanel                        matlab.ui.container.Panel
        ButtonsPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        RealignButton                   matlab.ui.control.Button
        RegisterButton                  matlab.ui.control.Button
        ExtrapolateMissingButton        matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        DisplayOptionsPanel             matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        OptodeAssignmentsCheckBox       matlab.ui.control.CheckBox
        UnassignedOptodesTCheckBox      matlab.ui.control.CheckBox
        UnassignedOptodesMCheckBox      matlab.ui.control.CheckBox
        ExtrapolatedOptodesTCheckBox    matlab.ui.control.CheckBox
        ExtrapolatedOptodesMCheckBox    matlab.ui.control.CheckBox
        ManuallyAssignedOptodesTCheckBox  matlab.ui.control.CheckBox
        ManuallyAssignedOptodesMCheckBox  matlab.ui.control.CheckBox
        AssignedModelOptodesTCheckBox   matlab.ui.control.CheckBox
        AssignedModelOptodesMCheckBox   matlab.ui.control.CheckBox
        UnassignedTemplateOptodesCheckBox  matlab.ui.control.CheckBox
        AssignedTemplateOptodesCheckBox  matlab.ui.control.CheckBox
        ModelSurfaceCheckBox            matlab.ui.control.CheckBox
        TemplateSurfaceCheckBox         matlab.ui.control.CheckBox
        OptodeAssignmentsLabel          matlab.ui.control.Label
        UnassignedOptodesLabel          matlab.ui.control.Label
        ExtrapolatedOptodesLabel        matlab.ui.control.Label
        ManuallyAssignedOptodesLabel    matlab.ui.control.Label
        AssignedModelOptodesLabel       matlab.ui.control.Label
        UnassignedTemplateOptodesLabel  matlab.ui.control.Label
        AssignedTemplateOptodesLabel    matlab.ui.control.Label
        ModelSurfaceLabel               matlab.ui.control.Label
        TemplateSurfaceLabel            matlab.ui.control.Label
        TemplateSurfaceLegendAx         matlab.ui.control.UIAxes
        ModelSurfaceLegendAx            matlab.ui.control.UIAxes
        AssignedTemplateOptodesLegendAx  matlab.ui.control.UIAxes
        UnassignedTemplateOptodesLegendAx  matlab.ui.control.UIAxes
        ManuallyAssignedOptodesLegendAx  matlab.ui.control.UIAxes
        AssignedModelOptodesLegendAx    matlab.ui.control.UIAxes
        ExtrapolatedOptodesLegendAx     matlab.ui.control.UIAxes
        UnassignedOptodesLegendAx       matlab.ui.control.UIAxes
        OptodeAssignmentsLegendAx       matlab.ui.control.UIAxes
        OptodeCntPanel                  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        OptodeCntTabHTML                matlab.ui.control.HTML
    end

        
    % TODOs:
    % - test extrapolation, add exception/fail handling
    % - save function
    % - update visibility according to checkBoxes when assignment is
    %   changed. see app.updateVisibility()   <<<---- TEST!
    % - outsource function to improver performance?
    
    properties (Access = public)
        % function handle to return property values on figure CloseRequest
        propReturnFnc = @(~)disp([])
        optodeMatchingCost (1,1) double = 15; % cost parameter for matchpair() used to assign optode identities; higher values allow higher deviations
    end
    
    
    properties (SetAccess = private, SetObservable, AbortSet)
        
        % struct containing template head surface ('faces', 'vertices' [, 'color'])
        tmpl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])

        % Mx3 array containing template optode coordinates
        tmpl_optodePositions (:,3) double
        % a copy of original positions
        tmpl_optodePositions_orig (:,3) double
        
        % cellstring of template optode IDs
        tmpl_optodeIDs (:,1) cell {iscellstr(tmpl_optodeIDs)} = cell(0,1)
        
        % struct containing model head surface ('faces', 'vertices' [, 'color'])
        modl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])
        
        % Mx3x2 array containing model optode coordinates (projections and origins)
        modl_optodePositions (:,3,2) double % = struct('xyz',[],'ID',[],'normals',[])
        
        % cellstring of model optode IDs (can be assigned from the GUI)
        modl_optodeIDs (:,1) cell {iscellstr(modl_optodeIDs)} = cell(0,1)
        
        % removed optodes
        modl_optodeIsRemoved (:,1) logical
        
        % manually assigned optode
        modl_optodeIsManAssigned (:,1) logical

        % optodes extrapolated from template
        modl_optodeIsExtrapolated (:,1) logical
    end
    
    
    properties (Constant)
        inputArgsPropertyNames (1,:) cell {OptodeReg.mustBeProperty(inputArgsPropertyNames,'ReviewOptodeIDs')} = ...
            {'tmpl_optodePositions', 'modl_optodePositions', ...
             'tmpl_optodeIDs',       'modl_optodeIDs', ... modl_optodeIDs have to be set AFTER tmpl-IDs and *optodePositions!
             'tmpl_headSurface',     'modl_headSurface', ...
             'tmpl_optodePositions_orig', 'modl_optodeIsRemoved', ...
             'modl_optodeIsManAssigned', 'modl_optodeIsExtrapolated'};
    end
    
    
    properties (SetAccess = private)
        optMarkerContextMenu (1,1) matlab.ui.container.ContextMenu % the context menu
        lastSelectedTmplOpt (1,1) uint16 = 0

        hndl_tmplOptodeMarker (:,1) matlab.graphics.chart.primitive.Line
        hndl_tmplOptodeID (:,1) matlab.graphics.primitive.Text
        hndl_modlOptodeMarker (:,2) matlab.graphics.chart.primitive.Line
        hndl_modlOptodeID (:,1) matlab.graphics.primitive.Text
        hndl_optodeAssignLine (:,1) matlab.graphics.Graphics
        hndl_modlOptodeContextMenu (:,1) matlab.graphics.Graphics % the menu entries within optMarkerContextMenu

        hndl_tmplHeadSurf (1,1) matlab.graphics.primitive.Patch
        hndl_modlHeadSurf (1,1) matlab.graphics.primitive.Patch

        isModified (1,1) logical = false;
    end
    
    
    properties (Constant, Hidden)
        props_tmplHeadSurf (1,1) struct = struct(...
                         'FaceAlpha',          .3, ...
                         'PickableParts',      'none', ...
                         'HitTest',            'off', ...
                         'Tag',                'tmplHeadSurf');
        props_modlHeadSurf (1,1) struct = struct(...
                         'FaceAlpha',          1, ...
                         'PickableParts',      'none', ...
                         'HitTest',            'off', ...
                         'Tag',                'modlHeadSurf');
        props_assignLine (1,1) struct = struct(...
                         'LineWidth',           2, ...
                         'Color',               'k', ...
                         'LineStyle',           '-', ...
                         'Tag',                 'optAssignLine');
        props_tmplOptode (1,1) struct = struct(...
                         'LineStyle',           '-', ...
                         'LineWidth',           1, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'MarkerEdgeColor',     'k',...
                         'Tag',                 'tmpl_optPosMarker');
        props_modlOptode (1,1) struct = struct(...
                         'LineStyle',           '-', ...
                         'LineWidth',           3, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'MarkerEdgeColor',     'none',...
                         'Tag',                 'modl_optPosMarker');
        props_tmplOptID (1,1) struct = struct(...
                         'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 'tmpl_optPosID');
        props_modlOptID (1,1) struct = struct(...
                         'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 'modl_optPosID');
        props_isUnassignedTmpl (1,1) struct = struct(...
                         'MarkerEdgeColor',     [255, 255, 255]./255, ...
                         'LineWidth',           2);
        props_isSrcMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [255, 146, 112]./255);
        props_isDetMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [121, 191, 252]./255);
        props_isOtherMarker (1,1) struct = struct(...
                         'MarkerFaceColor',     [245, 245, 245]./255);
        props_isUnassigned (1,1) struct = struct(...
                         'MarkerFaceColor',     [242, 236, 0]./255);
        props_manAssigned (1,1) struct = struct(...
                         'MarkerEdgeColor',     [242, 236, 0]./255);
        props_extrapolated (1,1) struct = struct(...
                         'MarkerEdgeColor',     'w', ...
                         'MarkerSize',          28, ...
                         'LineWidth',           1.5, ...
                         'Marker',              'h');
    end


    %% ###################### SET METHODS ############################
    
    
    methods 


        %..................................................................
            
        function set.tmpl_optodePositions(app,val)
            app.tmpl_optodePositions = val;
%             app.plotOptPositions('tmpl');
        end
        
        %..................................................................
            
        function set.tmpl_optodeIDs(app,val)
            msg ='';
            if any(cellfun(@isempty,val))
                msg = 'Template optode ID must not be empty!';
            end
            if numel(val)~=numel(unique(val))
                msg = 'Template optode IDs must be unique!';
            end
            if ~isempty(msg)
                error(msg);
            end
            
            app.tmpl_optodeIDs = val;
        end
        
        %..................................................................
        
        function set.tmpl_headSurface(app,val)
            app.tmpl_headSurface = val;
            app.plotSurf('tmpl');
        end
        
        %..................................................................
        
%         function set.modl_optodePositions(app,val)
%             app.modl_optodePositions = val;
%             app.plotOptPositions('modl');
%         end
        %..................................................................
        
        function set.modl_headSurface(app,val)
            app.modl_headSurface = val;
            app.plotSurf('modl');
        end
        
        %..................................................................
        
        function set.modl_optodeIDs(app,val)
            nNew = numel(val); nOld = numel(app.modl_optodeIDs);
            
            if nNew ~= size(app.modl_optodePositions,1)
                error('Number of optode IDs does not match number of positions. Set positions before IDs!')
            end
            
            if nNew == nOld
                k = ~strcmp(val(:),app.modl_optodeIDs(:));
            else  % nOld == 0 || nNew~=nOld
                k = 1:nNew;
%             elseif nNew > nOld
%                 k = ~strcmp(val(1:nOld),app.modl_optodeIDs(:));
            end
            app.modl_optodeIDs = val;
%             app.updateAssignment(k);
        end
        
        %..................................................................

        function set.modl_optodeIsManAssigned(app,val)
            app.modl_optodeIsManAssigned = val;
            if sum(val)>2
                app.RealignButton.Enable = true;
            else
                app.RealignButton.Enable = false;
            end
        end


    %% ###################### GET METHODS ############################


%         function val = get.optodeIDs(app)
%             if isfield(app.tmpl_optodePositions,'ID')
%                 val = app.tmpl_optodePositions.ID;
%             else
%                 val = [];
%             end
%         end
        

    end
    
    
    %% #################### METHODS public ################################
    
    
    methods (Access = public)
        
        
        function createOptContextMenu(app)
            val = app.tmpl_optodeIDs;
            
            try delete(app.hndl_modlOptodeContextMenu); end
            app.hndl_modlOptodeContextMenu(:) = [];

            delete(app.optMarkerContextMenu.Children);
            uimenu(app.optMarkerContextMenu,'Text',     'clear ID', ...
                    'MenuSelectedFcn',                  @(src,evt)app.cb_assignOptID(src,evt), ...
                    'UserData',                         -1);
            
            uimenu(app.optMarkerContextMenu,'Text',     'remove Optode', ...
                    'MenuSelectedFcn',                  @(src,evt)app.cb_assignOptID(src,evt), ...
                    'UserData',                         -2);
            
            uimenu(app.optMarkerContextMenu,'Text',     'restore removed Optodes', ...
                    'MenuSelectedFcn',                  @(src,evt)app.cb_assignOptID(src,evt), ...
                    'UserData',                         -3);
            
            detM = uimenu(app.optMarkerContextMenu,'Text','Detectors');
            srcM = uimenu(app.optMarkerContextMenu,'Text','Sources');
            [optIDs,idx] = sort(val);
            
            for k = 1:numel(optIDs)%:-1:1
                menuProps = {'Text',            optIDs{k}, ...
                             'MenuSelectedFcn', @(src,evt)app.cb_assignOptID(src,evt), ...
                             'UserData',        idx(k)};
                
                if ~isempty(regexpi(optIDs{k},'^d\d+$','once')) %  startsWith(optIDs{k},'S','IgnoreCase',true)
                    app.hndl_modlOptodeContextMenu(idx(k)) = uimenu(detM,menuProps{:});
                    
                elseif ~isempty(regexpi(optIDs{k},'^s\d+$','once')) % startsWith(optIDs{k},'D',"IgnoreCase",true)
                    app.hndl_modlOptodeContextMenu(idx(k)) = uimenu(srcM,menuProps{:});
                    
                else
                    app.hndl_modlOptodeContextMenu(idx(k)) = uimenu(app.optMarkerContextMenu,menuProps{:});
                end
            end
        end
        
        
        % =================================================================
        
        
        function plotModlOptPositions(app,kModl)
            if nargin<2
                kModl = 1:size(app.modl_optodePositions,1);
            end
            pos = app.modl_optodePositions(kModl,:,:);
            optAxis = diff(pos,1,3);
            pos(:,:,2) = pos(:,:,2) + optAxis.*.1;
            ids = app.modl_optodeIDs(kModl,:);
            nOpt = size(pos,1);
            
            P = [fieldnames(app.props_modlOptode) struct2cell(app.props_modlOptode)].';
            plotProps = [P(:)', ...
                         {'ContextMenu',         app.optMarkerContextMenu, ...
                         'ButtonDownFcn',       @(src,evt)app.cb_assignOptID(src,evt)}];
            textProps =  [fieldnames(app.props_modlOptID) struct2cell(app.props_modlOptID)].';
                        
            for k = nOpt:-1:1
                ph(k,1) = plot3(app.UIAxes_modl, ...
                                  pos(k,1,2), ...
                                  pos(k,2,2), ...
                                  pos(k,3,2), ...
                            'UserData',         kModl(k),...
                            plotProps{:});
                ph(k,2) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1,1), ...
                                  pos(k,2,1), ...
                                  pos(k,3,1), ...
                            'UserData',         kModl(k),...
                            plotProps{:});
            end
            
            assert(nOpt==numel(ids),'Number of model optode positions and model optode IDs do not match!');
            th(:,1) = text(app.UIAxes_modl, ...
                pos(:,1,2), pos(:,2,2), pos(:,3,2), ...
                ids,...{k}
                ...'UserData',         k,...
                textProps{:});
            kCell = num2cell(kModl);
            [th.UserData] = kCell{:};

            app.hndl_modlOptodeID(kModl) = th;
            app.hndl_modlOptodeMarker(kModl,:) = ph;
        end
        
        
        % =================================================================
        
        
        function plotTmplOptPositions(app)
            pos = app.tmpl_optodePositions;
            ids = app.tmpl_optodeIDs;
                        
            P = [fieldnames(app.props_tmplOptode) struct2cell(app.props_tmplOptode)].';
            plotProps = [P(:)', ...
                         {'ButtonDownFcn'},       {@(src,evt)app.cb_setLastSelTmplOpt(src,evt)}];
            
            textProps = [fieldnames(app.props_tmplOptID) struct2cell(app.props_tmplOptID)].';
            
            for k = size(pos,1):-1:1
                ph(k,1) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1), ...
                                  pos(k,2), ...
                                  pos(k,3), ...
                            'UserData',         k,...
                            plotProps{:});
            end
            
            assert(size(pos,1)==numel(ids),'Number of template optode positions and template optode IDs do not match!');
            for k = numel(ids):-1:1
                th(k,1) = text(app.UIAxes_tmpl, ...
                    pos(k,1), pos(k,2), pos(k,3), ...
                    ids{k},...
                    'UserData',         k,...
                    textProps{:});
                if startsWith(ids{k},'S')
                    set(ph(k), app.props_isSrcMarker);
                elseif startsWith(ids{k},'D')
                    set(ph(k), app.props_isDetMarker);
                else
                    set(ph(k), app.props_isOtherMarker);
                end
            end

            app.hndl_tmplOptodeMarker = ph;
            app.hndl_tmplOptodeID = th;
        end
        
        
        % =================================================================
        
        
        function plotSurf(app,typ)
            typ = validatestring(typ,{'modl','tmpl'});
            ah = app.(sprintf('UIAxes_%s',typ));
            srf = app.(sprintf('%s_headSurface',typ));
            propNam = sprintf('hndl_%sHeadSurf',typ);
            
            srfProps = app.(sprintf('props_%sHeadSurf',typ));
            
            ph = patch(ah,'faces',srf.faces, 'vertices',srf.vertices, ...
                       'EdgeColor',             'none', ...
                       'AlphaDataMapping',      'none', ...
                       'FaceLighting',          'gouraud');
            set(ph,srfProps);
            
            if isfield(srf,'color') && ~isempty(srf.color)
                set(ph,'FaceVertexCData', srf.color,...
                    'FaceColor',     'interp');
            else
                set(ph,'FaceColor', [1 1 1] .* .65);
                delete(findobj(ah,'Type','light'));
                lh(1,1) = lightangle(ah,-150,35);
                lh(1,2) = lightangle(ah,150,35);
                lh(1,3) = lightangle(ah,0,35);

            end
            material(ph,'dull');
            
            app.(propNam) = ph;
%             set(ah,'XLimMode','manual','YLimMode','manual','ZLimMode','manual');
        end
         
        
        % =================================================================
       
        
        function updateAssignment(app,idx)
            % update lines connecting matched optodes in the template axis
                        
            if      nargin<2,       idx = 1:numel(app.modl_optodeIDs);
            elseif  islogical(idx), idx = find(idx);
            end
            if      isempty(idx),   return; end
            
            selIDs = app.modl_optodeIDs(idx);
            [isAssigned,locb] = ismember(selIDs,app.tmpl_optodeIDs);
            
            tmplIsAssigned = ismember(app.tmpl_optodeIDs,app.modl_optodeIDs);
            set(app.hndl_tmplOptodeMarker( tmplIsAssigned),app.props_tmplOptode);
            set(app.hndl_tmplOptodeMarker(~tmplIsAssigned),app.props_isUnassignedTmpl);
            
            if ~all(isAssigned | cellfun(@isempty,selIDs))
                msg = [ {'These optodes are not present in the template:'}; ...
                        selIDs(~isAssigned & ~cellfun(@isempty,selIDs)); ...
                        {'Wrong template selected?'}];
                uialert(app.ReviewOptodeAssignmentsUIFigure,...
                    msg, 'Optode ID missmatch');
                warning('%s\n',msg{:});
            end
            

            % update isManAssigned
            app.modl_optodeIsManAssigned = app.modl_optodeIsManAssigned & ~cellfun(@isempty,app.modl_optodeIDs);

            % delete respective connections
            nLn = numel(app.hndl_optodeAssignLine);
            delete(app.hndl_optodeAssignLine(idx(idx<=nLn)));

            % update optode ID string
            set(app.hndl_modlOptodeID(idx),{'String'},selIDs);

            % update marker style
            h = app.hndl_modlOptodeMarker;
            k = idx(~isAssigned);
            set(h(k,:),app.props_isUnassigned);

            k = idx(startsWith(selIDs,'S'));
            set(h(k,:),app.props_isSrcMarker);

            k = idx(startsWith(selIDs,'D'));
            set(h(k,:),app.props_isDetMarker);

            k = idx(isAssigned & startsWith(selIDs,regexpPattern('[^SD]')));
            set(h(k,:),app.props_isOtherMarker);
            
            k = idx(app.modl_optodeIsManAssigned(idx));
            set(h(k,:),app.props_manAssigned);
            
            k = idx(app.modl_optodeIsExtrapolated(idx));
            set(h(k,:),app.props_extrapolated);


            % redraw connection
            kModl = idx(isAssigned);
            kTmpl = locb(isAssigned);
            xyz = cat(3,app.modl_optodePositions(kModl,:,1),app.tmpl_optodePositions(kTmpl,:));
            xyzCell = shiftdim(num2cell(permute(xyz,[3 1 2]),1),1);
            app.hndl_optodeAssignLine(kModl) = plot(app.UIAxes_tmpl,nan(2,numel(kModl))); % matlab.graphics.chart.primitive.Line('Parent',app.UIAxes_tmpl);
            set(app.hndl_optodeAssignLine(kModl), ...
                {'XData','YData','ZData', 'UserData',       'Parent'},...
                [xyzCell                  num2cell(kModl.') repmat({app.UIAxes_tmpl},numel(kModl),1)]);
            set(app.hndl_optodeAssignLine(kModl), app.props_assignLine);
        end
        
        
        % =================================================================
       
       
        function updateVisbility(app,cbh)
            if nargin<2 %|| isempty(cbh)
                cbh = findall(app.DisplayOptionsPanel,'Type','uicheckbox');
            end
            
            for h = cbh(:)'
                % get graphics handles
                switch h.Tag
                    case 'surf',          gfx = [app.hndl_tmplHeadSurf; app.hndl_modlHeadSurf];
                    case 't_modlOptode',  gfx =  app.hndl_modlOptodeMarker(:,2);
                    case 'm_modlOptode',  gfx = [app.hndl_modlOptodeMarker(:,1) app.hndl_modlOptodeID];
                    case 'tmplOptode',    gfx = [app.hndl_tmplOptodeMarker app.hndl_tmplOptodeID];
                    case 'assignCon',     gfx =  app.hndl_optodeAssignLine;
                    otherwise, error('Unexpected tag of callback object ''%s''. This is a bug, please report. Sorry.', h.Tag);
                end
    
                % get indices
                switch h
                    case app.TemplateSurfaceCheckBox
                        k=1;
                    case app.ModelSurfaceCheckBox
                        k=2;
                    case app.AssignedTemplateOptodesCheckBox
                        k = ismember(app.tmpl_optodeIDs,app.modl_optodeIDs);
                    case app.UnassignedTemplateOptodesCheckBox
                        k = ~ismember(app.tmpl_optodeIDs,app.modl_optodeIDs);
                    case {app.AssignedModelOptodesMCheckBox app.AssignedModelOptodesTCheckBox}
                        k = ~app.modl_optodeIsManAssigned & ~app.modl_optodeIsExtrapolated & ~cellfun(@isempty,app.modl_optodeIDs);
                    case {app.ManuallyAssignedOptodesMCheckBox app.ManuallyAssignedOptodesTCheckBox}
                        k =  app.modl_optodeIsManAssigned & ~app.modl_optodeIsExtrapolated & ~app.modl_optodeIsRemoved;
                    case {app.ExtrapolatedOptodesMCheckBox app.ExtrapolatedOptodesTCheckBox}
                        k = ~cellfun(@isempty,app.modl_optodeIDs) & app.modl_optodeIsExtrapolated & ~app.modl_optodeIsRemoved;
                    case {app.UnassignedOptodesMCheckBox app.UnassignedOptodesTCheckBox}
                        k = cellfun(@isempty,app.modl_optodeIDs) & ~app.modl_optodeIsRemoved;
                    case app.OptodeAssignmentsCheckBox
                        k = true(size(gfx,1),1);
                    otherwise, error('Unexpected callback source ''%s'' (%s)! This is a bug, please report. Sorry.',app.getPropName(h),h.Type);
                end
                
                gfx = gfx(k,:);
                gfx = gfx(isprop(gfx,'Visible'));
                set(gfx,'Visible',h.Value);
            end
        end
        
        % =================================================================
       
       
        function assignOptID(app,modlK,tmplK)
            % remove other assignmets of the selected template optode:
            kClear = ismember(app.modl_optodeIDs,app.tmpl_optodeIDs(tmplK));
            app.modl_optodeIDs(kClear) = {''};
            % assign new optodes
            app.modl_optodeIDs(modlK) = app.tmpl_optodeIDs(tmplK);
            % update graphics.
            app.updateAssignment([find(kClear);modlK]);
            app.updateVisbility();
        end
        
        
        % =================================================================


        function transformTmpl(app,TM)

            xyz0 = app.tmpl_optodePositions; % cell2mat(get(app.hndl_tmplOptodeMarker,{'XData','YData','ZData'}));
            xyz = ApplyTM(TM,xyz0);

            % markers
            set(app.hndl_tmplOptodeMarker,{'XData','YData','ZData'},num2cell(xyz));

            % IDs
            set(app.hndl_tmplOptodeID,{'Position'},num2cell(xyz,2));

            % assign lines
            isLine = ishandle(app.hndl_optodeAssignLine);
            [kModl,locb] = ismember(app.modl_optodeIDs,app.tmpl_optodeIDs);
            kModl = kModl & isLine;
            xyzLn = cat(3,app.modl_optodePositions(kModl,:,1), xyz(locb(kModl),:));
            xyzLnCell = shiftdim(num2cell(permute(xyzLn,[3 1 2]),1),1);
            set(app.hndl_optodeAssignLine(kModl),{'XData','YData','ZData'},xyzLnCell);

            % template head surface
            app.hndl_tmplHeadSurf.Vertices = ApplyTM(TM, app.hndl_tmplHeadSurf.Vertices);

            app.tmpl_optodePositions = xyz;

%             app.RegisterButton.Enable = true;
        end
        
        
        % =================================================================


        function resetTmplAlignment(app)

            xyz = app.tmpl_optodePositions_orig;

            % markers
            set(app.hndl_tmplOptodeMarker,{'XData','YData','ZData'},num2cell(xyz));

            % IDs
            set(app.hndl_tmplOptodeID,{'Position'},num2cell(xyz,2));

            % assign lines
            isLine = ishandle(app.hndl_optodeAssignLine);
            [kModl,locb] = ismember(app.modl_optodeIDs,app.tmpl_optodeIDs);
            kModl = kModl & isLine;
            xyzLn = cat(3,app.modl_optodePositions(kModl,:,1), xyz(locb(kModl),:));
            xyzLnCell = shiftdim(num2cell(permute(xyzLn,[3 1 2]),1),1);
            set(app.hndl_optodeAssignLine(kModl),{'XData','YData','ZData'},xyzLnCell);

            % template head surface
            app.hndl_tmplHeadSurf.Vertices = app.tmpl_headSurface.vertices;

            app.tmpl_optodePositions = xyz;

%             app.RegisterButton.Enable = false;
        end
        

        % =================================================================


        function extrapolateMissingOpt(app)
            [kTmpl,locb] = ismember(app.tmpl_optodeIDs,app.modl_optodeIDs);

            if all(kTmpl) % everything assigned already, nothing to do
                uialert(app.ReviewOptodeAssignmentsUIFigure,...
                    {'All template optodes are already assigned.'}, ...
                    'Nothing to extrapolate','Icon','success');
                return;
            end

            xyzTmpl = app.tmpl_optodePositions_orig(kTmpl,:);
            xyzTmpl_ua = app.tmpl_optodePositions_orig(~kTmpl,:);
            xyzModl_headSurf = app.modl_optodePositions(locb(kTmpl),:,1);
            xyzModl_optTop = app.modl_optodePositions(locb(kTmpl),:,2);

            idTmpl_ua = app.tmpl_optodeIDs(~kTmpl);


            if 0 % exist("estimateGeometricTransform3D",'file')
                % AFFINE TRANSFORM, REQUIRES COMPUTER VISION TOOLBOX! slow,
                % not very good if many optodes missing?! -----------------
                [TM,inlierIdx,stats] = estimateGeometricTransform3D(xyzTmpl, xyzModl_headSurf, 'similarity', 'MaxNumTrials',50000); 
                xyzModl_ua_headSurf = transformPointsForward(TM, xyzTmpl_ua);

                % affine transform to estimate missing optode tops from projections
                [TM] = estimateGeometricTransform3D(xyzModl_headSurf, xyzModl_optTop, 'similarity', 'MaxNumTrials',50000);
                xyzModl_ua_optTop = transformPointsForward(TM, xyzModl_ua_headSurf);
            
            else
                % RIGID TRANSFORM - MUCH faster, even more precise?! -----
                [~,TM] = OptodeReg.registerFiducials(xyzTmpl, xyzModl_headSurf);
                xyzModl_ua_headSurf = ApplyTM(TM,xyzTmpl_ua);
                kOt = {startsWith(app.modl_optodeIDs,'s','IgnoreCase',true); ...
                       startsWith(app.modl_optodeIDs,'d','IgnoreCase',true)};
                kOt{3} = ~kOt{1} & ~kOt{2};


                % estimate missing optode tops. E.g. find 95 percentile of
                % optode length (seperately for Src and Det!) and use
                % optode normal to project from head surf back to optode
                % top.

                cvHull = mkConvexHull(app.tmpl_headSurface);
                xNormals = getOptodeTangentPlane(xyzModl_ua_headSurf, ...
                                                 cvHull.vertices, cvHull.faces, ...
                                                 true,true);
                
                kOt_ua = {startsWith(idTmpl_ua,'s','IgnoreCase',true); ...
                          startsWith(idTmpl_ua,'d','IgnoreCase',true)};
                kOt_ua{3} = ~kOt_ua{1} & ~kOt_ua{2};

                szs = sqrt(sum(diff(app.modl_optodePositions,1,3).^2,2));
                xyzModl_ua_optTop = nan(size(xyzModl_ua_headSurf));
                for ot = 1:3
                    k = kOt{ot};
                    k_ua = kOt_ua{ot};
                    optSz = quantile(szs(k),.95);
                    if isnan(optSz), optSz = quantile(szs,.95); end
                    xyzModl_ua_optTop(k_ua,:) = xyzModl_ua_headSurf(k_ua,:) + xNormals(k_ua,:).*optSz;
                end
            end


            nX = sum(~kTmpl);
            app.modl_optodePositions = [app.modl_optodePositions; ...
                                        cat(3,xyzModl_ua_headSurf, xyzModl_ua_optTop)]; % <<<<<--------- opt origins
            app.modl_optodeIsRemoved = [app.modl_optodeIsRemoved; ...
                                        false(nX,1)];
            app.modl_optodeIsManAssigned = [app.modl_optodeIsManAssigned; ...
                                        false(nX,1)];
            app.modl_optodeIsExtrapolated = [app.modl_optodeIsExtrapolated; ...
                                        true(nX,1)];
            app.modl_optodeIDs = [app.modl_optodeIDs; ...
                                  idTmpl_ua];
            
            % plot extrapolated positions
            app.plotModlOptPositions((-nX:-1)+1+numel(app.modl_optodeIDs))

            app.updateAssignment();
            app.updateVisbility();
        end


        % =================================================================


        function deleteOptodes(app,kModl)
            app.modl_optodePositions        = app.modl_optodePositions(~kModl,:);
            app.modl_optodeIsRemoved        = app.modl_optodeIsRemoved(~kModl,:);
            app.modl_optodeIsManAssigned    = app.modl_optodeIsManAssigned(~kModl,:);
            app.modl_optodeIsExtrapolated   = app.modl_optodeIsExtrapolated(~kModl,:);
            app.modl_optodeIDs              = app.modl_optodeIDs(~kModl,:);

            app.updateAssignment();
            app.updateVisbility();
        end


        % =================================================================


        function highlightTmplOptode(app,k,reset)
            if nargin>2 && ~reset
            else
                set(app.hndl_tmplOptodeID,'FontWeight','normal');
                set(app.hndl_tmplOptodeMarker,'LineWidth',app.props_tmplOptode.LineWidth);
            end
            if isnumeric(k)
                set(app.hndl_tmplOptodeID(k),'FontWeight','bold');
                set(app.hndl_tmplOptodeMarker(k),'LineWidth',app.props_tmplOptode.LineWidth+1.5);
            end
        end
       
    end % end methods public
    
    
    %% ####################### METHODS private ############################
    
    
    methods (Access = private)
        

        function cb_assignOptID(app,src,evnt)
            
            if isprop(evnt,'Button')
            % clicked an optode . . . . . . . . . . . . . . . . . . . . . .
                [k_modlOpt, k_ax] = find(src==app.hndl_modlOptodeMarker);
                if isempty(k_modlOpt), error('Failed to identify optode marker from callback source. This is a bug. sorry.'); end
                if evnt.Button == 1
                % left click - assign previous selected template optode
                    if app.lastSelectedTmplOpt > 0
                        app.modl_optodeIsManAssigned(k_modlOpt) = true;
                        app.assignOptID(k_modlOpt,app.lastSelectedTmplOpt);
                        app.lastSelectedTmplOpt = 0;
                        app.highlightTmplOptode([],true);
                    end
                elseif evnt.Button == 3
                % right click - save clicked optode in contextMenu
                    app.optMarkerContextMenu.UserData = k_modlOpt;

                    k_tmplOpt = ismember(app.tmpl_optodeIDs,app.modl_optodeIDs{k_modlOpt});
                    set(app.hndl_modlOptodeContextMenu,'Checked',false);
                    set(app.hndl_modlOptodeContextMenu(k_tmplOpt),'Checked',true);
                end
                
                
            elseif isa(src,'matlab.ui.container.Menu')
            % clicked context menu . . . . . . . . . . . . . . . . . . . . 
                k_modlOpt = app.optMarkerContextMenu.UserData;
                ud = src.UserData;
                if isnumeric(ud) && isscalar(ud)
                    if ud>0
                        k_tmplOpt = ud;
                        app.modl_optodeIsManAssigned(k_modlOpt) = true;
                        app.assignOptID(k_modlOpt,k_tmplOpt);
                    elseif ud == -1 % unassign ID
                        app.modl_optodeIDs{k_modlOpt} = '';
                        app.updateAssignment(k_modlOpt);
                        app.updateVisbility();
                    elseif ud == -2 % unassign and remove (hide) optode
                        if ~isempty(app.modl_optodeIDs{k_modlOpt})
                            resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure,...
                                'This optode is currently assigned. Are you sure to remove this optode? Assignment will be lost!',...
                                'Remove assigned optode?','Options',{'yes','no'},'CancelOption',2,'DefaultOption',1,...
                                'Icon','warning');
                            if ~strcmpi(resp,'yes')
                                return;
                            end
                        end
                        app.modl_optodeIDs{k_modlOpt} = '';
                        app.modl_optodeIsRemoved(k_modlOpt) = true;
                        app.updateAssignment(k_modlOpt);
                        app.updateVisbility();
                        set([app.hndl_modlOptodeID(k_modlOpt) ...
                             app.hndl_modlOptodeMarker(k_modlOpt,:)], 'Visible','off');
                    elseif ud == -3 % restore 'deleted' optodes
                        app.modl_optodeIsRemoved(:) = false;
                        set([app.hndl_modlOptodeID...
                             app.hndl_modlOptodeMarker], 'Visible','on');
                    end                    
                end

            else
                error('Unexpected input to callback function. Please report this bug. Sorry.');
            end
        end

        
        % =================================================================
        

        function cb_setLastSelTmplOpt(app,src,evnt)
            kTmpl = find(src==app.hndl_tmplOptodeMarker);
            if isempty(kTmpl), error('Failed to identify optode marker from callback source. This is a bug. sorry.'); end
            if app.lastSelectedTmplOpt == kTmpl
                app.lastSelectedTmplOpt = 0;
                app.highlightTmplOptode([],true);
            else
                app.lastSelectedTmplOpt = kTmpl;
                app.highlightTmplOptode(kTmpl);
            end
        end
        

        % =================================================================        
        

        function plotLegends(app)

            % template surface
            k=1;
            % too time consuming!
%             [f,v] = reducepatch(app.hndl_tmplHeadSurf.Faces,app.hndl_tmplHeadSurf.Vertices,40); 
            h(k) = patch(app.TemplateSurfaceLegendAx, ...'Faces',f,'Vertices',v, ...
                'Tag',app.props_tmplHeadSurf.Tag,'Visible','off');
            
            % model surface
            k=k+1;
%             [f,v] = reducepatch(app.hndl_modlHeadSurf.Faces,app.hndl_modlHeadSurf.Vertices,40);
            h(k) = patch(app.ModelSurfaceLegendAx, ...'Faces',f,'Vertices',v, ...
                'Tag',app.props_modlHeadSurf.Tag,'Visible','off');
            
            % assigned template optode
            k=k+1;
            h(k) = plot(app.AssignedTemplateOptodesLegendAx,1,1,app.props_tmplOptode);
            set(h(k),app.props_isSrcMarker);
            k=k+1;
            h(k) = text(app.AssignedTemplateOptodesLegendAx,1,1,'S01',app.props_tmplOptID);
            
            % unassigned template optode
            k=k+1;
            h(k) = plot(app.UnassignedTemplateOptodesLegendAx,1,1,app.props_tmplOptode);
            set(h(k),app.props_isSrcMarker);
            set(h(k),app.props_isUnassignedTmpl);
            k=k+1;
            h(k) = text(app.UnassignedTemplateOptodesLegendAx,1,1,'S01',app.props_tmplOptID);
            
            % assigned optodes
            k=k+1;
            h(k) = plot(app.AssignedModelOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            k=k+1;
            h(k) = text(app.AssignedModelOptodesLegendAx,1,1,'S01',app.props_modlOptID);
            
            % manually assigned optodes
            k=k+1;
            h(k) = plot(app.ManuallyAssignedOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            set(h(k),app.props_manAssigned);
            k=k+1;
            h(k) = text(app.ManuallyAssignedOptodesLegendAx,1,1,'S01',app.props_modlOptID);
            
            % extrapolated optodes
            k=k+1;
            h(k) = plot(app.ExtrapolatedOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isSrcMarker);
            set(h(k),app.props_extrapolated);
            k=k+1;
            h(k) = text(app.ExtrapolatedOptodesLegendAx,1,1,'S01',app.props_modlOptID);
            
            % unassigned optodes
            k=k+1;
            h(k) = plot(app.UnassignedOptodesLegendAx,1,1,app.props_modlOptode);
            set(h(k),app.props_isUnassigned);
            
            % assignment line
            k=k+1;
            h(k) = plot(app.OptodeAssignmentsLegendAx,[0 2],[1 1],app.props_assignLine);

            set(h,'PickableParts','none','HitTest','off');
        end
        

        % =================================================================        

        
        function cb_updateOptCountsTable(app,src,evnt)
            cnt.nAssgn = sum(~cellfun(@isempty,app.modl_optodeIDs));
            cnt.nSrcAssgn = sum(startsWith(app.modl_optodeIDs,'s','IgnoreCase',true));
            cnt.nDetAssgn = sum(startsWith(app.modl_optodeIDs,'d','IgnoreCase',true));
            cnt.nOpt = numel(app.tmpl_optodeIDs);
            cnt.nSrc = sum(startsWith(app.tmpl_optodeIDs,'s','IgnoreCase',true));
            cnt.nDet = sum(startsWith(app.tmpl_optodeIDs,'d','IgnoreCase',true));
            cnt.nUnassgn = cnt.nOpt-cnt.nAssgn;
            cnt.nSrcUnassgn = cnt.nSrc-cnt.nSrcAssgn;
            cnt.nDetUnassgn = cnt.nDet-cnt.nDetAssgn;

            app.OptodeCntTabHTML.Data = cnt;
%             {nSrcAssgn       nDetAssgn       nAssgn; ...
%                                             nSrc-nSrcAssgn  nDet-nDetAssgn  nOpt-nAssgn; ...
%                                             nSrc            nDet            nOpt};
        end

        
        % =================================================================        
        

        function storeResults(app)
            vals = cellfun(@(x)app.(x),app.inputArgsPropertyNames,'UniformOutput',false);
            saveVar = cell2struct(vals(:),app.inputArgsPropertyNames(:),1);
            try
                app.propReturnFnc(saveVar);
            catch ME
                uialert(app.ReviewOptodeAssignmentsUIFigure, ...
                    {'Failed to execute property save function because:', ME.message}, ...
                    'Error saving results');
                rethrow(ME);
            end
            app.setModified(false);
        end


        % =================================================================        
        
        
        function setModified(app,val)
            app.isModified = val;
        end


        % =================================================================        
        

        function propNam = getPropName(app,prop)
            P = properties(app);
            k = cellfun(@(x)all(app.(x)==prop,'all'),P);
            propNam = P{k};            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            
            % link view props for axes
            Link = linkprop([app.UIAxes_modl app.UIAxes_tmpl], ...
                {'CameraUpVector', 'CameraPosition', ...
                 'CameraTarget', 'XLim', 'YLim', 'ZLim'});
            setappdata(app.ReviewOptodeAssignmentsUIFigure, 'AxesLink', Link);
            axis([app.UIAxes_modl app.UIAxes_tmpl],'vis3d','equal');
            
            % create context menu for optode markers
            app.optMarkerContextMenu = uicontextmenu(app.ReviewOptodeAssignmentsUIFigure);
            app.RestoreremovedoptodesMenu.UserData = -3;
            

            % create PostSet event listener for optode counts table
            addlistener(app,'modl_optodeIDs','PostSet',@(src,evnt)app.cb_updateOptCountsTable(src,evnt));

            getRepNoHref = @(me)regexprep(getReport(me,'basic','hyperlinks','off'),'</?a[^>]*>','');
            

            % process input arguments
            nvain = numel(varargin);
            for k = 1:nvain
                if isempty(varargin{k}), continue; end
                if k<=numel(app.inputArgsPropertyNames)
                    pn = app.inputArgsPropertyNames{k};
                    try
                        app.(pn) = varargin{k};                        
                    catch ME
                        msg = {sprintf('Failed to assign input argument %d to property ''%s'' because:',k,pn), ...
                               getRepNoHref(ME)};
                        uialert(app.ReviewOptodeAssignmentsUIFigure,...
                            msg, 'Failed to set app property value');
                        rethrow(ME);
                    end
                elseif rem(k-numel(app.inputArgsPropertyNames),2)==1
                    pn = varargin{k};
                else
                    app.(pn) = varargin{k};
                end
            end
            
            
            % check input and create graphics
            try
                if isempty(app.modl_optodeIDs)
                    app.modl_optodeIDs = repmat({''},size(app.modl_optodePositions,1),1);
                else, assert(numel(app.modl_optodeIDs) == size(app.modl_optodePositions,1), ...
                        'Number of model optode IDs and positions do not match!');
                end
                app.modl_optodeIDs = app.modl_optodeIDs(:);

                if isempty(app.modl_optodeIsManAssigned)
                    app.modl_optodeIsManAssigned = false(size(app.modl_optodeIDs));
                else, assert(numel(app.modl_optodeIDs) == numel(app.modl_optodeIsManAssigned), ...
                        'Number of model optode IDs and modl_optodeIsManAssigned do not match!');
                end
                app.modl_optodeIsManAssigned = app.modl_optodeIsManAssigned(:);

                if isempty(app.modl_optodeIsRemoved)
                    app.modl_optodeIsRemoved = false(size(app.modl_optodeIDs));
                else, assert(numel(app.modl_optodeIDs) == numel(app.modl_optodeIsRemoved), ...
                        'Number of model optode IDs and modl_optodeIsRemoved do not match!');
                end
                app.modl_optodeIsRemoved = app.modl_optodeIsRemoved(:);

                if isempty(app.modl_optodeIsExtrapolated)
                    app.modl_optodeIsExtrapolated = false(size(app.modl_optodeIDs));
                else, assert(numel(app.modl_optodeIDs) == numel(app.modl_optodeIsExtrapolated), ...
                        'Number of model optode IDs and modl_optodeIsExtrapolated do not match!');
                end

                app.modl_optodeIsExtrapolated = app.modl_optodeIsExtrapolated(:);

                if isempty(app.tmpl_optodePositions_orig)
                    app.tmpl_optodePositions_orig = app.tmpl_optodePositions;
                end
                assert(isequal(size(app.tmpl_optodePositions_orig), ...
                               size(app.tmpl_optodePositions)), ...
                       'Template optode positions and original positions must be of same size!');

                [~, TM] = OptodeReg.registerFiducials(app.tmpl_optodePositions, app.tmpl_optodePositions_orig);
                app.hndl_tmplHeadSurf.Vertices = ApplyTM(TM, app.hndl_tmplHeadSurf.Vertices);

                app.plotModlOptPositions();
                app.plotTmplOptPositions();
                app.createOptContextMenu();
                app.updateAssignment();
            catch ME
                msg = {'There was an error while setting up the graphics objects:', ...
                       getRepNoHref(ME)};
                uialert(app.ReviewOptodeAssignmentsUIFigure,...
                    msg, 'Error setting up graphics');
                rethrow(ME);
            end
            
            % create PostSet event listener for setting isModified
            addlistener(app,'modl_optodeIDs','PostSet',@(src,evnt)app.setModified(true));

            % plot legends
            app.plotLegends();
        end

        % Callback function: AssignedModelOptodesLegendAx, 
        % ...and 21 other components
        function cb_triggerGfxVisibility(app, event)
            h = event.Source;
%             hName = app.getPropName(h);
%             if strcmpi(h.Type,'axes')
% %                 ah = h;
%                 h = app.(regexprep(hName,'LegendAx$','CheckBox','once'));
%                 h.Value = ~h.Value;
            if strcmpi(h.Type,'uicheckbox')
%                 ah = app.(regexprep(hName,'CheckBox$','LegendAx','once'));
            else
                warning('Unexpected callback source ''%s'' (%s)!',app.getPropName(h),h.Type);
                return;
            end
            
            app.updateVisbility(h);
        end

        % Button pushed function: RealignButton
        function cb_realignButton(app, event)
            kModl = find(app.modl_optodeIsManAssigned);
            [kTmpl,locb] = ismember(app.tmpl_optodeIDs,app.modl_optodeIDs(kModl));
            kModl = kModl(locb(kTmpl));
            kTmpl = find(kTmpl);

            xyzTmpl = app.tmpl_optodePositions(kTmpl,:); % cell2mat(get(app.hndl_tmplOptodeMarker(kTmpl),{'XData','YData','ZData'}));
            xyzModl = app.modl_optodePositions(kModl,:,1); % cell2mat(get(app.hndl_modlOptodeMarker(kModl,2),{'XData','YData','ZData'}));
            [~, TM] = OptodeReg.registerFiducials(xyzTmpl,xyzModl);

            app.transformTmpl(TM);
        end

        % Menu selected function: ResetalignmentMenu
        function cb_resetAlignment(app, event)
%             if isequaln(app.tmpl_optodePositions,app.tmpl_optodePositions_orig), return; end

            resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure,...
                {'Are you sure to restore original orientation of template?','This cannot be undone!'},'Restore alignment?',...
                'Options',{'yes','no'},'CancelOption',2,'DefaultOption',1);
            if ~strcmpi(resp,'yes'), return; end

            app.resetTmplAlignment();

        end

        % Menu selected function: RestoreremovedoptodesMenu
        function cb_restoreRemovedMenu(app, event)
            app.cb_assignOptID(event.Source,event);
        end

        % Button pushed function: RegisterButton
        function cb_registerButton(app, event)
            resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure,...
                'All optode IDs will be reassigned. This cannot be undone!', ...
                'Re-Register Optodes?', 'Options',{'continue','cancel'},'CancelOption',2,'DefaultOption',1);
            if ~strcmpi(resp,'continue'), return; end

            xyzTmpl = app.tmpl_optodePositions;
            xyzModl = app.modl_optodePositions(:,:,1);
            idTmpl = app.tmpl_optodeIDs;
            [app.modl_optodeIDs,unmtchTmpl,unmtchModl] = OptodeReg.matchOptodes(xyzTmpl, xyzModl, idTmpl, app.optodeMatchingCost);
            app.updateAssignment();
        end

        % Button pushed function: ExtrapolateMissingButton
        function cb_extrapolateMissButton(app, event)
            if any(app.modl_optodeIsExtrapolated) % remove previously extrapolated opts?
                resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure, ...
                    {'Do you want to remove previously extrapolated optodes?', 'This cannot be undone!'}, ...
                    'Delete previously extrapolated optodes?', 'Options',{'yes','no'},'CancelOption',2,'DefaultOption',1);
                if strcmpi(resp,'yes')
                    app.deleteOptodes(app.modl_optodeIsExtrapolated);
                end
            end

            app.extrapolateMissingOpt();
        end

        % Menu selected function: DeleteextrapolatedoptodesMenu
        function cb_deleteExtrapolatedOptodes(app, event)
            if ~any(app.modl_optodeIsExtrapolated), return; end % nothing to do

            resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure, ...
                {'Are you sure to delete all extrapolated optodes?', 'This cannot be undone!'}, ...
                'Delete extrapolated optodes?', 'Options',{'yes','no'},'CancelOption',2,'DefaultOption',1);
            if ~strcmpi(resp,'yes'), return; end % user is unsure

            app.deleteOptodes(app.modl_optodeIsExtrapolated);
        end

        % Button pushed function: SaveButton
        function cb_saveButton(app, event)
            app.storeResults();
            close(app.ReviewOptodeAssignmentsUIFigure);
        end

        % Callback function
        function cb_PanelAdaptWidth(app, event)
%             position = app.DisplayOptionsPanel.Position;
            return;         % DEPRECATED!   
            newWdth = event.Source.Position(3);
            app.OptodeCntPanel.Position(3) = newWdth;
            app.DisplayOptionsPanel.Position(3) = newWdth;
            app.ButtonsPanel.Position(3) = newWdth;
            app.ButtonsPanel.Position(2) = app.ReviewOptodeAssignmentsUIFigure.Position(4)-app.ButtonsPanel.Position(4);

%             xP = newWdth = event.Source.Position(1);
%             for h = [app.RealignButton app.RegisterButton app.ExtrapolateMissingButton app.SaveButton]
%                 x = h.Position(1);
% %                 h.Position(3) = newWdth - 2*x;
%             end
        end

        % Key press function: ReviewOptodeAssignmentsUIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            [az, el] = view(app.UIAxes_tmpl);
            switch key
                case 'rightarrow'
                    view(app.UIAxes_tmpl, wrapTo180(az+10), el);
                case 'leftarrow'
                    view(app.UIAxes_tmpl, wrapTo180(az-10), el);
                case 'uparrow'
                    view(app.UIAxes_tmpl, az, wrapTo180(el+10));
                case 'downarrow'
                    view(app.UIAxes_tmpl, az, wrapTo180(el-10));
            end
        end

        % Close request function: ReviewOptodeAssignmentsUIFigure
        function ROIFigureCloseRequest(app, event)
            if app.isModified
                resp = uiconfirm(app.ReviewOptodeAssignmentsUIFigure, ...
                    {'Do you want to save results before closing the app?', 'Changes will be lost!'}, ...
                    'Save changes?', 'Options',{'yes','no','cancle'},'CancelOption',3,'DefaultOption',1);
                if strcmpi(resp,'yes'), app.storeResults();
                elseif strcmpi(resp,'cancle'), return;
                elseif strcmpi(resp,'no') % pass
                else, error('This is a bug.');
                end
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReviewOptodeAssignmentsUIFigure = uifigure('Visible', 'off');
            app.ReviewOptodeAssignmentsUIFigure.Name = 'Review Optode Assignments';
            app.ReviewOptodeAssignmentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ROIFigureCloseRequest, true);
            app.ReviewOptodeAssignmentsUIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.EditMenu = uimenu(app.ReviewOptodeAssignmentsUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ResetalignmentMenu = uimenu(app.EditMenu);
            app.ResetalignmentMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_resetAlignment, true);
            app.ResetalignmentMenu.Text = 'Reset alignment';
            app.RestoreremovedoptodesMenu = uimenu(app.EditMenu);
            app.RestoreremovedoptodesMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_restoreRemovedMenu, true);
            app.RestoreremovedoptodesMenu.Text = 'Restore removed optodes';
            app.DeleteextrapolatedoptodesMenu = uimenu(app.EditMenu);
            app.DeleteextrapolatedoptodesMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_deleteExtrapolatedOptodes, true);
            app.DeleteextrapolatedoptodesMenu.Text = 'Delete extrapolated optodes';
            app.CtrPanel = uipanel(app.ReviewOptodeAssignmentsUIFigure);
            app.OptodeCntPanel = uipanel(app.CtrPanel);
            app.OptodeCntPanel.Title = '  Optode Counts';
            app.GridLayout2 = uigridlayout(app.OptodeCntPanel);
            app.OptodeCntTabHTML = uihtml(app.GridLayout2);
            app.OptodeCntTabHTML.HTMLSource = 'optodeCntTable.html';
            app.DisplayOptionsPanel = uipanel(app.CtrPanel);
            app.DisplayOptionsPanel.Title = '  Display Options / Legend';
            app.GridLayout = uigridlayout(app.DisplayOptionsPanel);
            app.OptodeAssignmentsLegendAx = uiaxes(app.GridLayout);
            app.OptodeAssignmentsLegendAx.XLim = [0 2];
            app.OptodeAssignmentsLegendAx.YLim = [0 2];
            app.OptodeAssignmentsLegendAx.XTick = [];
            app.OptodeAssignmentsLegendAx.YTick = [];
            app.OptodeAssignmentsLegendAx.ZTick = [];
            app.OptodeAssignmentsLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedOptodesLegendAx = uiaxes(app.GridLayout);
            app.UnassignedOptodesLegendAx.XLim = [0 2];
            app.UnassignedOptodesLegendAx.YLim = [0 2];
            app.UnassignedOptodesLegendAx.XTick = [];
            app.UnassignedOptodesLegendAx.YTick = [];
            app.UnassignedOptodesLegendAx.ZTick = [];
            app.UnassignedOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ExtrapolatedOptodesLegendAx = uiaxes(app.GridLayout);
            app.ExtrapolatedOptodesLegendAx.XLim = [0 2];
            app.ExtrapolatedOptodesLegendAx.YLim = [0 2];
            app.ExtrapolatedOptodesLegendAx.XTick = [];
            app.ExtrapolatedOptodesLegendAx.YTick = [];
            app.ExtrapolatedOptodesLegendAx.ZTick = [];
            app.ExtrapolatedOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedModelOptodesLegendAx = uiaxes(app.GridLayout);
            app.AssignedModelOptodesLegendAx.XLim = [0 2];
            app.AssignedModelOptodesLegendAx.YLim = [0 2];
            app.AssignedModelOptodesLegendAx.XTick = [];
            app.AssignedModelOptodesLegendAx.YTick = [];
            app.AssignedModelOptodesLegendAx.ZTick = [];
            app.AssignedModelOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ManuallyAssignedOptodesLegendAx = uiaxes(app.GridLayout);
            app.ManuallyAssignedOptodesLegendAx.XLim = [0 2];
            app.ManuallyAssignedOptodesLegendAx.YLim = [0 2];
            app.ManuallyAssignedOptodesLegendAx.XTick = [];
            app.ManuallyAssignedOptodesLegendAx.YTick = [];
            app.ManuallyAssignedOptodesLegendAx.ZTick = [];
            app.ManuallyAssignedOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedTemplateOptodesLegendAx = uiaxes(app.GridLayout);
            app.UnassignedTemplateOptodesLegendAx.XLim = [0 2];
            app.UnassignedTemplateOptodesLegendAx.YLim = [0 2];
            app.UnassignedTemplateOptodesLegendAx.XTick = [];
            app.UnassignedTemplateOptodesLegendAx.YTick = [];
            app.UnassignedTemplateOptodesLegendAx.ZTick = [];
            app.UnassignedTemplateOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedTemplateOptodesLegendAx = uiaxes(app.GridLayout);
            app.AssignedTemplateOptodesLegendAx.XLim = [0 2];
            app.AssignedTemplateOptodesLegendAx.YLim = [0 2];
            app.AssignedTemplateOptodesLegendAx.XTick = [];
            app.AssignedTemplateOptodesLegendAx.YTick = [];
            app.AssignedTemplateOptodesLegendAx.ZTick = [];
            app.AssignedTemplateOptodesLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ModelSurfaceLegendAx = uiaxes(app.GridLayout);
            app.ModelSurfaceLegendAx.XTick = [];
            app.ModelSurfaceLegendAx.YTick = [];
            app.ModelSurfaceLegendAx.ZTick = [];
            app.ModelSurfaceLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateSurfaceLegendAx = uiaxes(app.GridLayout);
            app.TemplateSurfaceLegendAx.XTick = [];
            app.TemplateSurfaceLegendAx.YTick = [];
            app.TemplateSurfaceLegendAx.ZTick = [];
            app.TemplateSurfaceLegendAx.ButtonDownFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateSurfaceLabel = uilabel(app.GridLayout);
            app.TemplateSurfaceLabel.Text = 'Template Surface';
            app.ModelSurfaceLabel = uilabel(app.GridLayout);
            app.ModelSurfaceLabel.Text = 'Model Surface';
            app.AssignedTemplateOptodesLabel = uilabel(app.GridLayout);
            app.AssignedTemplateOptodesLabel.Text = 'Assigned Template Optodes';
            app.UnassignedTemplateOptodesLabel = uilabel(app.GridLayout);
            app.UnassignedTemplateOptodesLabel.Text = 'Unassigned Template Optodes';
            app.AssignedModelOptodesLabel = uilabel(app.GridLayout);
            app.AssignedModelOptodesLabel.Text = 'Assigned Model Optodes';
            app.ManuallyAssignedOptodesLabel = uilabel(app.GridLayout);
            app.ManuallyAssignedOptodesLabel.Text = 'Manually Assigned Optodes';
            app.ExtrapolatedOptodesLabel = uilabel(app.GridLayout);
            app.ExtrapolatedOptodesLabel.Text = 'Extrapolated Optodes';
            app.UnassignedOptodesLabel = uilabel(app.GridLayout);
            app.UnassignedOptodesLabel.Text = 'Unassigned Optodes';
            app.OptodeAssignmentsLabel = uilabel(app.GridLayout);
            app.OptodeAssignmentsLabel.Text = 'Optode Assignments';
            app.TemplateSurfaceCheckBox = uicheckbox(app.GridLayout);
            app.TemplateSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.TemplateSurfaceCheckBox.Tag = 'surf';
            app.TemplateSurfaceCheckBox.Text = '';
            app.TemplateSurfaceCheckBox.Value = true;
            app.ModelSurfaceCheckBox = uicheckbox(app.GridLayout);
            app.ModelSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ModelSurfaceCheckBox.Tag = 'surf';
            app.ModelSurfaceCheckBox.Text = '';
            app.ModelSurfaceCheckBox.Value = true;
            app.AssignedTemplateOptodesCheckBox = uicheckbox(app.GridLayout);
            app.AssignedTemplateOptodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedTemplateOptodesCheckBox.Tag = 'tmplOptode';
            app.AssignedTemplateOptodesCheckBox.Text = '';
            app.AssignedTemplateOptodesCheckBox.Value = true;
            app.UnassignedTemplateOptodesCheckBox = uicheckbox(app.GridLayout);
            app.UnassignedTemplateOptodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedTemplateOptodesCheckBox.Tag = 'tmplOptode';
            app.UnassignedTemplateOptodesCheckBox.Text = '';
            app.UnassignedTemplateOptodesCheckBox.Value = true;
            app.AssignedModelOptodesMCheckBox = uicheckbox(app.GridLayout);
            app.AssignedModelOptodesMCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedModelOptodesMCheckBox.Tag = 'm_modlOptode';
            app.AssignedModelOptodesMCheckBox.Text = '';
            app.AssignedModelOptodesMCheckBox.Value = true;
            app.AssignedModelOptodesTCheckBox = uicheckbox(app.GridLayout);
            app.AssignedModelOptodesTCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.AssignedModelOptodesTCheckBox.Tag = 't_modlOptode';
            app.AssignedModelOptodesTCheckBox.Text = '   ';
            app.AssignedModelOptodesTCheckBox.Value = true;
            app.ManuallyAssignedOptodesMCheckBox = uicheckbox(app.GridLayout);
            app.ManuallyAssignedOptodesMCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ManuallyAssignedOptodesMCheckBox.Tag = 'm_modlOptode';
            app.ManuallyAssignedOptodesMCheckBox.Text = '';
            app.ManuallyAssignedOptodesMCheckBox.Value = true;
            app.ManuallyAssignedOptodesTCheckBox = uicheckbox(app.GridLayout);
            app.ManuallyAssignedOptodesTCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ManuallyAssignedOptodesTCheckBox.Tag = 't_modlOptode';
            app.ManuallyAssignedOptodesTCheckBox.Text = '   ';
            app.ManuallyAssignedOptodesTCheckBox.Value = true;
            app.ExtrapolatedOptodesMCheckBox = uicheckbox(app.GridLayout);
            app.ExtrapolatedOptodesMCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ExtrapolatedOptodesMCheckBox.Tag = 'm_modlOptode';
            app.ExtrapolatedOptodesMCheckBox.Text = '';
            app.ExtrapolatedOptodesMCheckBox.Value = true;
            app.ExtrapolatedOptodesTCheckBox = uicheckbox(app.GridLayout);
            app.ExtrapolatedOptodesTCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.ExtrapolatedOptodesTCheckBox.Tag = 't_modlOptode';
            app.ExtrapolatedOptodesTCheckBox.Text = '   ';
            app.ExtrapolatedOptodesTCheckBox.Value = true;
            app.UnassignedOptodesMCheckBox = uicheckbox(app.GridLayout);
            app.UnassignedOptodesMCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedOptodesMCheckBox.Tag = 'm_modlOptode';
            app.UnassignedOptodesMCheckBox.Text = '';
            app.UnassignedOptodesMCheckBox.Value = true;
            app.UnassignedOptodesTCheckBox = uicheckbox(app.GridLayout);
            app.UnassignedOptodesTCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.UnassignedOptodesTCheckBox.Tag = 't_modlOptode';
            app.UnassignedOptodesTCheckBox.Text = '   ';
            app.UnassignedOptodesTCheckBox.Value = true;
            app.OptodeAssignmentsCheckBox = uicheckbox(app.GridLayout);
            app.OptodeAssignmentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_triggerGfxVisibility, true);
            app.OptodeAssignmentsCheckBox.Tag = 'assignCon';
            app.OptodeAssignmentsCheckBox.Text = '';
            app.OptodeAssignmentsCheckBox.Value = true;
            app.ButtonsPanel = uipanel(app.CtrPanel);
            app.GridLayout3 = uigridlayout(app.ButtonsPanel);
            app.SaveButton = uibutton(app.GridLayout3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @cb_saveButton, true);
            app.SaveButton.Text = 'Save';
            app.ExtrapolateMissingButton = uibutton(app.GridLayout3, 'push');
            app.ExtrapolateMissingButton.ButtonPushedFcn = createCallbackFcn(app, @cb_extrapolateMissButton, true);
            app.ExtrapolateMissingButton.Text = 'Extrapolate Missing';
            app.RegisterButton = uibutton(app.GridLayout3, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @cb_registerButton, true);
            app.RegisterButton.Text = 'Re-Register';
            app.RealignButton = uibutton(app.GridLayout3, 'push');
            app.RealignButton.ButtonPushedFcn = createCallbackFcn(app, @cb_realignButton, true);
            app.RealignButton.Enable = 'off';
            app.RealignButton.Tooltip = {'Manually assign at least 3 Optode IDs using the context menu in the left panel to realign the optode positions to the template. Then run registration again.'};
            app.RealignButton.Text = 'Realign';
            app.AxesPanel = uipanel(app.ReviewOptodeAssignmentsUIFigure);
            app.UIAxes_modl = uiaxes(app.AxesPanel);
            app.UIAxes_modl.XTick = [];
            app.UIAxes_modl.YTick = [];
            app.UIAxes_modl.ZTick = [];
            app.UIAxes_tmpl = uiaxes(app.AxesPanel);
            app.UIAxes_tmpl.XTick = [];
            app.UIAxes_tmpl.YTick = [];
            app.UIAxes_tmpl.ZTick = [];
            app.ModelAxisTitleLabel = uilabel(app.AxesPanel);
            app.ModelAxisTitleLabel.Interpreter = 'html';
            app.ModelAxisTitleLabel.Text = '<center><b>Model</b></br> ';
            app.TemplateAxisTitleLabel = uilabel(app.AxesPanel);
            app.TemplateAxisTitleLabel.Interpreter = 'html';
            app.TemplateAxisTitleLabel.Text = '<center><b>Template</b></br>(Realigned to model optode positions)</center>';
            % Show the figure after all components are created
            app.ReviewOptodeAssignmentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReviewOptodeIDs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReviewOptodeAssignmentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReviewOptodeAssignmentsUIFigure)
        end
    end
end