classdef SetFiducials < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ShortcutsLabel             matlab.ui.control.Label
        DoneButton                 matlab.ui.control.Button
        SelectFiducialButtonGroup  matlab.ui.container.ButtonGroup
        RightPreauricularButton    matlab.ui.control.ToggleButton
        LeftPreauricularButton     matlab.ui.control.ToggleButton
        NasionButton               matlab.ui.control.ToggleButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (GetAccess = public, SetAccess = private)
        fiducials = struct('Nasion',NaN(1,3),'LPA',NaN(1,3),'RPA',NaN(1,3)); % Description
    end
    
    properties (Access = private)
        assignFnc = [];
        markerHandles = struct('Nasion',[],'LPA',[],'RPA',[]); % fields have to match the Tags of the Buttons!
    end
    

    methods
        function set.fiducials(app,fids)
            app.fiducials = fids;
            app.updateFidsMarker();
        end
    end


    methods (Access = public)
        function cb_setFiducial(app,ph,evnt)
            field = app.SelectFiducialButtonGroup.SelectedObject.Tag;
            app.fiducials.(field) = evnt.IntersectionPoint;
        end
        
        function updateFidsMarker(app)
            fields = fieldnames(app.markerHandles);
            [x,y,z] = sphere(20);
            for k = 1:numel(fields)
                if numel(app.fiducials.(fields{k}))<3, continue; end
                fid = app.fiducials.(fields{k});
                app.markerHandles.(fields{k}).XData = x * 5 + fid(1);
                app.markerHandles.(fields{k}).YData = y * 5 + fid(2);
                app.markerHandles.(fields{k}).ZData = z * 5 + fid(3);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, headModel, assignFnc, fiducials)
            ph = patch('Parent',        app.UIAxes, ...
                       'Faces',         headModel.faces, ...
                       'Vertices',      headModel.vertices, ...
                       'EdgeColor',     'none', ...
                       'FaceVertexCData',headModel.color, ...
                       'FaceColor',     'interp', ...
                       'FaceLighting',  'gouraud', ...
                       'ButtonDownFcn', @(h,evnt)app.cb_setFiducial(h,evnt), ...
                       'Tag',           'headModelPatch');

            axis(app.UIAxes,'vis3d','equal');
            fidfields = fieldnames(app.fiducials);
            
            for k = 1:numel(fidfields)
                btn = findobj(app.SelectFiducialButtonGroup,'Tag',fidfields{k});
                fcol = btn.BackgroundColor;
%                 mcol = btn.FontColor;
%                 app.markerHandles.(fields{k}) = plot3(app.UIAxes,NaN,NaN,NaN,...
%                     'MarkerFaceColor',fcol,'MarkerEdgeColor',[1 1 1],'MarkerSize',9,'Marker','o');
                app.markerHandles.(fidfields{k}) = surf(app.UIAxes,NaN(2),NaN(2),NaN(2),...
                    'FaceColor',fcol,'EdgeColor','none','HitTest','off','PickableParts','none');
            end


            if nargin > 2
                app.assignFnc = assignFnc;
            end
            
            if nargin > 3
                if all(ismember(fieldnames(fiducials),fidfields))
                    app.fiducials = fiducials;
                else
                    uialert(app.UIFigure,'The provided fiducials are invalid. Pass a structure with fields ''Nasion'', ''RPA'' and ''LPA''!',...
                        'Invalid fiducials','Icon','info');
                end
            end

        end

        % Callback function: DoneButton, UIFigure
        function UIFigureCloseRequest(app, event)
            assignVal = ~isempty(app.assignFnc);

            if ~isequal(event.Source,app.DoneButton) && assignVal
                resp = uiconfirm(app.UIFigure,'Do you want to save the fiducials?','Save fiducials?',...
                            'Options',{'yes','no'},'CancelOption',2,'DefaultOption',1);
                if strcmpi(resp,'no')
                    assignVal = false;
                end
            end

            if assignVal
                try
                    app.assignFnc(app.fiducials);
                catch ME
                    warning('getFiducialUI:closeRequest:failedToAssignFiducials',...
                        'Failed to assign fiducials because\n%s',ME.getReport);
                    resp = uiconfirm(app.UIFigure, {'Failed to assign fiducials because:',ME.message,'Save to base workspace?'},'Failed to save fiducials', ...
                        'Options',{'yes','no'},'DefaultOption',1,'CancelOption',2);
                    if strcmpi(resp,'yes')
                        var = sprintf('SELECTED_FIDUCIALS_%s',datestr(datetime,'HHMMSS'));
                        assignin("base",var,app.fiducials);
                    end
                end
            end
            delete(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            [az, el] = view(app.UIAxes);
            switch key
                case 'l'
                    app.SelectFiducialButtonGroup.SelectedObject = app.LeftPreauricularButton;
                    app.SelectFiducialButtonGroupSelectionChanged([]);
                case 'r'
                    app.SelectFiducialButtonGroup.SelectedObject = app.RightPreauricularButton;
                    app.SelectFiducialButtonGroupSelectionChanged([]);
                case 'n'
                    app.SelectFiducialButtonGroup.SelectedObject = app.NasionButton;
                    app.SelectFiducialButtonGroupSelectionChanged([]);
                case 'rightarrow'
                    view(app.UIAxes, wrapTo180(az+10), el);
                case 'leftarrow'
                    view(app.UIAxes, wrapTo180(az-10), el);
                case 'uparrow'
                    view(app.UIAxes, az, wrapTo180(el+10));
                case 'downarrow'
                    view(app.UIAxes, az, wrapTo180(el-10));
            end
%             disp(key);
        end

        % Selection changed function: SelectFiducialButtonGroup
        function SelectFiducialButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectFiducialButtonGroup.SelectedObject;
            arrayfun(@(x)set(x,'FontWeight','normal','FontColor',[1 1 1]*.8),app.SelectFiducialButtonGroup.Children);
            selectedButton.FontWeight = 'bold';
            selectedButton.FontColor = [1 1 1];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Select Fiducials';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-20 20];
            app.UIAxes.XTickLabel = {'-'; '+'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-20 20];
            app.UIAxes.YTickLabel = {'-'; '+'};
            app.UIAxes.ZTick = [-20 20];
            app.UIAxes.ZTickLabel = {'-'; '+'};
            app.SelectFiducialButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectFiducialButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectFiducialButtonGroupSelectionChanged, true);
            app.SelectFiducialButtonGroup.Title = 'Select Fiducial';
            app.NasionButton = uitogglebutton(app.SelectFiducialButtonGroup);
            app.NasionButton.Tag = 'Nasion';
            app.NasionButton.Text = 'Nasion';
            app.NasionButton.Value = true;
            app.LeftPreauricularButton = uitogglebutton(app.SelectFiducialButtonGroup);
            app.LeftPreauricularButton.Tag = 'LPA';
            app.LeftPreauricularButton.Text = 'Left Preauricular';
            app.RightPreauricularButton = uitogglebutton(app.SelectFiducialButtonGroup);
            app.RightPreauricularButton.Tag = 'RPA';
            app.RightPreauricularButton.Text = 'Right Preauricular';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DoneButton.Text = 'Done';
            app.ShortcutsLabel = uilabel(app.UIFigure);
            app.ShortcutsLabel.Interpreter = 'html';
            app.ShortcutsLabel.Text = {'<h1>Shortcuts</h1>'; '<b>N</b>: Select nasion<br>'; '<b>L</b>: Select left PA<br>'; '<b>R</b>: Select right PA<br>'; '&lt;<b>arrow keys</b>&gt;: move camera<br>'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetFiducials(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end